classdef Auto_DataROI_field < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        LoadDataButton        matlab.ui.control.Button
        FinishandBackButton   matlab.ui.control.Button
        CalculateFieldButton  matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

%%
%   TMS KoDeIn Box, Version 1.0, 12/2022
%   Kaiserslautern Coil Design and Instrument Box
%   Max Koehler and Stefan M. Goetz
%   (c) 2006-2022

    properties (Access = public)
        coil
        coilraw
        dAdtcoil
        vol
        sup
        AFlag
        r_min
        r_max
        theta_min
        theta_max
        phi_min
        phi_max
        hdr

        MainApp
        res
        roi
    end
    
    methods (Access = private)
%%  Function Update User Interface
        function updateui(app)
            hold (app.UIAxes,'off')
            
            if app.AFlag ==1

                hold (app.UIAxes,'on')
                
                %// Get the current colormap
                currentColormap = colormap(flipud(hot));
                close(gcf)
                n=10000;
                q=quiver3(app.UIAxes,app.sup(:,1:n:end),app.sup(:,2:n:end),app.sup(:,3:n:end),app.vol(:,1:n:end),app.vol(:,2:n:end),app.vol(:,3:n:end),2);
            
                %// Compute the magnitude of the vectors - q.UData r the
                %components of vectors plotted converted to column vectors
                %vectors concatenated into N x 3 matrix
                mags = sqrt(sum(cat(2, q.UData(:), q.VData(:), ...
                        reshape(q.WData, numel(q.UData), [])).^2, 2));
            
                %// Now determine the color to make each arrow using a colormap
                [~, ~, ind] = histcounts(mags, size(currentColormap, 1)); %bins the mags into intervals based on no. of colors available in currentColormap
                % ind provides color index for each vector based on
                % magnitude
            
                %// Now map this to a colormap to get RGB
                cmap = uint8(ind2rgb(ind(:), currentColormap) * 255); %convert ind into RGB color
                cmap(:,:,4) = 128; %sets transparancy [0:255]
                cmap = permute(repmat(cmap, [1 3 1]), [2 1 3]);
            
                %// We repeat each color 3 times (using 1:3 below) because each arrow has 3 vertices
                set(q.Head, ...
                    'ColorBinding', 'interpolated', ...
                    'ColorData', reshape(cmap(1:3,:,:), [], 4).');   %'
            
                %// We repeat each color 2 times (using 1:2 below) because each tail has 2 vertices
                set(q.Tail, ...
                    'ColorBinding', 'interpolated', ...
                    'ColorData', reshape(cmap(1:2,:,:), [], 4).');

                hold (app.UIAxes,'off')
               % app.UIAxes.Visible = 'on';
                     

            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, m_roi, m_res)
            app.res = m_res;
            app.roi = m_roi;

            app.MainApp = mainapp;
        end

        % Button pushed function: CalculateFieldButton
        function CalculateFieldButtonPushed(app, event)
            hdr = app.hdr;
            dims = size(hdr.vol);
            middle_voxel = floor(dims / 2);
            middle_real = hdr.sform * [middle_voxel, 1]'; % Homogeneous coordinates

            [x, y, z] = ndgrid(1:dims(1), 1:dims(2), 1:dims(3));
            voxel_coords = [x(:), y(:), z(:), ones(numel(x), 1)]; 
            real_coords = (hdr.sform * voxel_coords')';
              
            adjusted_coords = real_coords(:, 1:3) - middle_real(1:3)';

            voxelData = hdr.vol(:);
            x = adjusted_coords(:,1);
            y = adjusted_coords(:,2);
            z = adjusted_coords(:,3);
            
            nonZeroIdx = voxelData ~= 0;  % Indices of non-zero voxels
            zeroIdx = voxelData == 0;     % Indices of zero voxels
            
            voxelDataNonZero = voxelData(nonZeroIdx);
            realXNonZero = x(nonZeroIdx);
            realYNonZero = y(nonZeroIdx);
            realZNonZero = z(nonZeroIdx);
            coord(:,1)=reshape(realXNonZero,[],1);
            coord(:,2)=reshape(realYNonZero,[],1);
            coord(:,3)=reshape(realZNonZero,[],1);

            ROI = 130;
            Res = 2;
            gridpoints = (((ROI*2)/Res)+1); %no. of grid points in one direction (diameter / resolution)
            
            [xx,yy,zz]= meshgrid(linspace(-ROI,ROI,gridpoints),linspace(-ROI,ROI,gridpoints),linspace(-ROI,ROI,gridpoints));
            
            sup(:,1)=reshape(xx,[],1);
            sup(:,2)=reshape(yy,[],1);
            sup(:,3)=reshape(zz,[],1);
            
            no = vecnorm(sup,2,2);
            sup(no>ROI,:) =  []; %remove those points outside of ROI

            mask = ismember(sup, coord, 'rows');

            vol = zeros(size(sup));
            % vector = [1;2;3];
           % vector = [-.6450;.5803;0.4972];
            % % % % vector = [.6450;-.5803;-0.4972];
           % Perpendicular to Sulci [0.528608683222562;-0.736119032641306;-0.422691504128396;0.005789830965669]
            % vector =
            % [0.528608683222562;-0.736119032641306;-0.422691504128396]; %
            % this is incorrect - there is still a component normal to the
            % cortical surface
            %vector =[0.644716600344708,-0.062732238730034,-0.761843272244285]' %a_VEC
            vector = [0.519291134054841;-0.738309288789290;-0.430390650664593]
           % vector = [5.19291134054841;-7.38309288789290;-4.30390650664593]

            data = voxelDataNonZero * vector';
            vol(mask,:) = data;

            app.vol = vol;
            app.sup = sup;

            app.AFlag=1; %indicates calculations stopped
            % close(d)
            updateui(app);
        end

        % Button pushed function: FinishandBackButton
        function FinishandBackButtonPushed(app, event)
            % push field back to main app and close (check if field
            % calculation was successfull. If not, close with warning.
            if app.AFlag == 1
                push_A(app.MainApp, app.vol, app.sup)
                delete(app)
            end

        end

        % Callback function
        function Rmin_value_changed(app, event)
            app.r_min = app.R_minEditField.Value;
            
        end

        % Callback function
        function Rmax_value_changed(app, event)
            app.r_max = app.R_maxEditField.Value;
            
        end

        % Callback function
        function theta_min_variable(app, event)
            app.theta_min = app.theta_minEditField.Value;
            
        end

        % Callback function
        function theta_max_variable(app, event)
            app.theta_max = app.theta_maxEditField.Value;
            
        end

        % Callback function
        function phi_min_variable(app, event)
            app.phi_min = app.phi_minEditField.Value;
            
        end

        % Callback function
        function phi_max_variable(app, event)
            app.phi_max = app.phi_maxEditField.Value;
            
        end

        % Button pushed function: LoadDataButton
        function Load_Data(app, event)
            app.AFlag=0;
            [fileName, filePath] = uigetfile('*.nii', 'Select a NIfTI file');
            if fileName ~= 0
                fullFilePath = fullfile(filePath, fileName);
                disp(['Selected file: ', fullFilePath]);
            
                % Load the file using the path
                hdr = nifti_load(fullFilePath);
                app.hdr = nifti_load(fullFilePath);

            else
                disp('File selection canceled.');
            end
            
            CalculateFieldButtonPushed(app);
            FinishandBackButtonPushed(app);
           

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'x (mm)')
            ylabel(app.UIAxes, 'y (mm)')
            zlabel(app.UIAxes, 'z (mm)')
            app.UIAxes.XLim = [-200 200];
            app.UIAxes.YLim = [-200 200];
            app.UIAxes.ZLim = [-85 210];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [-200 0 200];
            app.UIAxes.XTickLabel = {'-200'; '0'; '200'};
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [-200 0 200];
            app.UIAxes.YTickLabel = {'-200'; '0'; '200'};
            app.UIAxes.ZTick = [-85 0 210];
            app.UIAxes.ZTickLabel = {'-85'; '0'; '210'};
            app.CalculateFieldButton = uibutton(app.UIFigure, 'push');
            app.CalculateFieldButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFieldButtonPushed, true);
            app.CalculateFieldButton.Tooltip = {'Calculate the magnetic vector potential A for the coil path. Calculations might take a while. The results will be displayed on the right.'};
            app.CalculateFieldButton.Text = 'Calculate Field';
            app.FinishandBackButton = uibutton(app.UIFigure, 'push');
            app.FinishandBackButton.ButtonPushedFcn = createCallbackFcn(app, @FinishandBackButtonPushed, true);
            app.FinishandBackButton.Tooltip = {''};
            app.FinishandBackButton.Text = 'Finish and Back!';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @Load_Data, true);
            app.LoadDataButton.Text = 'Load Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Auto_DataROI_field(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end