classdef Current_NEW_comatose_v01_Optimisation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SavedFileNameEditField          matlab.ui.control.EditField
        SavedFileNameEditFieldLabel     matlab.ui.control.Label
        RunEntireScriptButton           matlab.ui.control.Button
        ClosedLoopDiscretizationButton  matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.Button
        PersonalisedFieldButton         matlab.ui.control.Button
        OptimizationPanel               matlab.ui.container.Panel
        MaxIterationsEditField          matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel     matlab.ui.control.Label
        MaxFunEvalsEditField            matlab.ui.control.NumericEditField
        MaxFunEvalsEditFieldLabel       matlab.ui.control.Label
        FunctionToleranceEditField      matlab.ui.control.NumericEditField
        FunctionToleranceEditFieldLabel  matlab.ui.control.Label
        StepToleranceEditField          matlab.ui.control.NumericEditField
        StepToleranceEditFieldLabel     matlab.ui.control.Label
        RunOptimizationButton           matlab.ui.control.Button
        ConductordiscretizationPanel    matlab.ui.container.Panel
        ExportCoilButton                matlab.ui.control.Button
        BackwarddistEditField           matlab.ui.control.NumericEditField
        BackwarddistEditFieldLabel      matlab.ui.control.Label
        ForwarddistEditField            matlab.ui.control.NumericEditField
        ForwarddistEditFieldLabel       matlab.ui.control.Label
        RunDiscretizationButton         matlab.ui.control.Button
        spreadEditField                 matlab.ui.control.NumericEditField
        spreadEditFieldLabel            matlab.ui.control.Label
        discmassEditField               matlab.ui.control.NumericEditField
        discmassEditFieldLabel          matlab.ui.control.Label
        COilMAtchingTOolboxforLabel     matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        ModeResolution                  matlab.ui.control.NumericEditField
        ModeResolutionEditFieldLabel    matlab.ui.control.Label
        LoadFieldButton                 matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        Panel_3                         matlab.ui.container.Panel
        ShowCoilCheckBox                matlab.ui.control.CheckBox
        ShowFieldCheckBox               matlab.ui.control.CheckBox
        ShowCDCheckBox                  matlab.ui.control.CheckBox
        Panel_2                         matlab.ui.container.Panel
        maxresSlider                    matlab.ui.control.Slider
        maxresSliderLabel               matlab.ui.control.Label
        Spinner                         matlab.ui.control.Spinner
        ShowCoilProjectionSurface       matlab.ui.control.CheckBox
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        PlanaradjustmentsPanel          matlab.ui.container.Panel
        PlanesizeEditField              matlab.ui.control.NumericEditField
        PlanesizeEditFieldLabel         matlab.ui.control.Label
        AzimuthField                    matlab.ui.control.NumericEditField
        ElevationField                  matlab.ui.control.NumericEditField
        FindbestplacementButton         matlab.ui.control.Button
        AzimuthSlider                   matlab.ui.control.Slider
        AzimuthSliderLabel              matlab.ui.control.Label
        ElevationSlider                 matlab.ui.control.Slider
        ElevationSliderLabel            matlab.ui.control.Label
        CoiltypeButtonGroup             matlab.ui.container.ButtonGroup
        SphericalButton                 matlab.ui.control.RadioButton
        PlanarButton                    matlab.ui.control.RadioButton
        ResolutionField                 matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel        matlab.ui.control.Label
        ROIradiusEditField              matlab.ui.control.NumericEditField
        ROIradiusEditFieldLabel         matlab.ui.control.Label
        CoilradiusEditField             matlab.ui.control.NumericEditField
        CoilradiusEditFieldLabel        matlab.ui.control.Label
        COMATOSELabel                   matlab.ui.control.Label
        LoadCoilButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        Fieldapp
        Coilapp
        Property % Description
        coilraw
        AFlag
        BFlag
        roi_radius
        resolution
        A_Aim
        A_sup
        coiltype
        xs
        ys
        zs
        rot_p1
        rot_p2
        rot_p3
        rot_p4
        maxnum
        maxind
        Mu0
        hotspot
        support
        direction
        newcoil
        lookup
        lookupinv
        vecscale
        A_modes
        corrA
        CSU1
        CSUM
        CCSUM
        CSUMM
        newcoils_cd
        newcoils_A
        my_rot
        my_gridsize
        discoil
        discoilor
        opticoeff
        planarflag
        lastmaxval
        my_coillift
    end

    properties (Access = private)
        
        oldpath = addpath(genpath('external_functions'));
    end
    

    methods (Access = private)

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        
        function updateui(app)

            cla(app.UIAxes)
            cla(app.UIAxes_2)
            hold (app.UIAxes,'off')
            if app.AFlag ==1


                %// Get the current colormap
                currentColormap = colormap(hot);
                close(gcf)
%                 n=10;
                q=quiver3(app.UIAxes,app.A_sup(:,1),app.A_sup(:,2),app.A_sup(:,3),app.A_Aim(:,1),app.A_Aim(:,2),app.A_Aim(:,3),2);
            
                %// Compute the magnitude of the vectors
                mags = sqrt(sum(cat(2, q.UData(:), q.VData(:), ...
                        reshape(q.WData, numel(q.UData), [])).^2, 2));
            
                %// Now determine the color to make each arrow using a colormap
                [~, ~, ind] = histcounts(mags, size(currentColormap, 1));
            
                %// Now map this to a colormap to get RGB
                cmap = uint8(ind2rgb(ind(:), currentColormap) * 255);
                cmap(:,:,4) = 255;
                cmap = permute(repmat(cmap, [1 3 1]), [2 1 3]);
            
                %// We repeat each color 3 times (using 1:3 below) because each arrow has 3 vertices
                set(q.Head, ...
                    'ColorBinding', 'interpolated', ...
                    'ColorData', reshape(cmap(1:3,:,:), [], 4).');   %'
            
                %// We repeat each color 2 times (using 1:2 below) because each tail has 2 vertices
                set(q.Tail, ...
                    'ColorBinding', 'interpolated', ...
                    'ColorData', reshape(cmap(1:2,:,:), [], 4).');

                hold (app.UIAxes,'off')
                     
            end
            
            if app.ShowCoilProjectionSurface.Value == true

                if app.coiltype == 1
                    hold (app.UIAxes,'on')
                    surf(app.UIAxes,app.xs,app.ys,app.zs,'FaceColor','blue','FaceAlpha',0.2,'EdgeColor','none');
                elseif app.coiltype == 2 %%planar CD
                    hold (app.UIAxes,'on')
                    patch(app.UIAxes,[app.rot_p1(1) app.rot_p2(1) app.rot_p3(1) app.rot_p4(1)],[app.rot_p1(2) app.rot_p2(2) app.rot_p3(2) app.rot_p4(2)],[app.rot_p1(3) app.rot_p2(3) app.rot_p3(3) app.rot_p4(3)],'blue','FaceAlpha',0.2,'EdgeColor','none');
                end
                hold (app.UIAxes,'off')

            end


            if app.BFlag == 1

                if app.ShowCDCheckBox.Value == true

                    hold (app.UIAxes_2,'on') %% 2nd UI plot

                    if app.ShowFieldCheckBox.Value == false

                        surf(app.UIAxes_2,app.xs,app.ys,app.zs,'FaceColor','white','FaceAlpha',1,'EdgeColor','none'); % xs,ys,zs are data/coordinates for the surface plot

                    end
                    
                    %// Get the current colormap
                    currentColormap = colormap(hot);
                    close(gcf)
%                     n=10;
                    q=quiver3(app.UIAxes_2,app.support(:,1),app.support(:,2),app.support(:,3),app.newcoils_cd(:,1,app.maxresSlider.Value)./app.vecscale,app.newcoils_cd(:,2,app.maxresSlider.Value)./app.vecscale,app.newcoils_cd(:,3,app.maxresSlider.Value)./app.vecscale,2);
                
                    %// Compute the magnitude of the vectors
                    mags = sqrt(sum(cat(2, q.UData(:), q.VData(:), ...
                            reshape(q.WData, numel(q.UData), [])).^2, 2));
                
                    %// Now determine the color to make each arrow using a colormap
                    [~, ~, ind] = histcounts(mags, size(currentColormap, 1));
                
                    %// Now map this to a colormap to get RGB
                    cmap = uint8(ind2rgb(ind(:), currentColormap) * 255);
                    cmap(:,:,4) = 255;
                    cmap = permute(repmat(cmap, [1 3 1]), [2 1 3]);
                
                    %// We repeat each color 3 times (using 1:3 below) because each arrow has 3 vertices
                    set(q.Head, ...
                        'ColorBinding', 'interpolated', ...
                        'ColorData', reshape(cmap(1:3,:,:), [], 4).');   %'
                
                    %// We repeat each color 2 times (using 1:2 below) because each tail has 2 vertices
                    set(q.Tail, ...
                        'ColorBinding', 'interpolated', ...
                        'ColorData', reshape(cmap(1:2,:,:), [], 4).');
    
%                     hold (app.UIAxes_2,'off')

                end

                if app.ShowFieldCheckBox.Value == true 

%                     hold (app.UIAxes_2,'off')
                    
                    %// Get the current colormap
                    currentColormap = colormap(hot);
                    close(gcf)
%                     n=10;
                    q=quiver3(app.UIAxes_2,app.A_sup(:,1),app.A_sup(:,2),app.A_sup(:,3),app.newcoils_A(:,1,app.maxresSlider.Value),app.newcoils_A(:,2,app.maxresSlider.Value),app.newcoils_A(:,3,app.maxresSlider.Value),2);
                
                    %// Compute the magnitude of the vectors
                    mags = sqrt(sum(cat(2, q.UData(:), q.VData(:), ...
                            reshape(q.WData, numel(q.UData), [])).^2, 2));
                
                    %// Now determine the color to make each arrow using a colormap
                    [~, ~, ind] = histcounts(mags, size(currentColormap, 1));
                
                    %// Now map this to a colormap to get RGB
                    cmap = uint8(ind2rgb(ind(:), currentColormap) * 255);
                    cmap(:,:,4) = 255;
                    cmap = permute(repmat(cmap, [1 3 1]), [2 1 3]);
                
                    %// We repeat each color 3 times (using 1:3 below) because each arrow has 3 vertices
                    set(q.Head, ...
                        'ColorBinding', 'interpolated', ...
                        'ColorData', reshape(cmap(1:3,:,:), [], 4).');   %'
                
                    %// We repeat each color 2 times (using 1:2 below) because each tail has 2 vertices
                    set(q.Tail, ...
                        'ColorBinding', 'interpolated', ...
                        'ColorData', reshape(cmap(1:2,:,:), [], 4).');
    
%                     hold (app.UIAxes_2,'off')

                end

                if app.ShowCoilCheckBox.Value == true

                    hold (app.UIAxes,'on')

                        %line(app.UIAxes_2,app.discoil(:,2),app.discoil(:,1),app.discoil(:,3))
                        line(app.UIAxes_2,app.discoil(:,1),app.discoil(:,2),app.discoil(:,3))


                end

            end
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function makeplane(app)

            my_point1 = [ app.PlanesizeEditField.Value/2  app.PlanesizeEditField.Value/2   000];
            my_point2 = [app.PlanesizeEditField.Value/-2  app.PlanesizeEditField.Value/2   000];
            my_point3=  [app.PlanesizeEditField.Value/-2  app.PlanesizeEditField.Value/-2  000];
            my_point4 = [ app.PlanesizeEditField.Value/2  app.PlanesizeEditField.Value/-2  000];

            eul=[deg2rad(app.ElevationSlider.Value) 0 deg2rad(app.AzimuthSlider.Value)];

            app.my_rot = eul2rotm(eul,'XYZ'); % generates a rotation matrix

            app.my_coillift = [0 0 app.CoilradiusEditField.Value] * app.my_rot;

            app.rot_p1 = my_point1 * app.my_rot + app.my_coillift;
            app.rot_p2 = my_point2 * app.my_rot + app.my_coillift;
            app.rot_p3 = my_point3 * app.my_rot + app.my_coillift;
            app.rot_p4 = my_point4 * app.my_rot + app.my_coillift;

        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function makesphere(app)

            [app.xs,app.ys,app.zs] = sphere(50);
            app.xs = app.xs * app.CoilradiusEditField.Value;
            app.ys = app.ys * app.CoilradiusEditField.Value;
            app.zs = app.zs * app.CoilradiusEditField.Value;

        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function AMax(app)

            [app.maxnum, app.maxind] = max(vecnorm(app.A_Aim,2,2));

        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function generatecurrents(app)
            
            if app.coiltype == 1
                
                nn =            4;      % facettes of icosphere
                
                [app.support,app.direction,app.lookup,app.lookupinv,app.vecscale] = sphericalmode(app.ModeResolution.Value,app.CoilradiusEditField.Value, nn);
               
            elseif app.coiltype == 2
                
                app.my_gridsize=    121;    % Resolution in Coilregion
                
                [app.support,app.direction,app.lookup,app.lookupinv] = planarmode(app.my_gridsize,app.PlanesizeEditField.Value,app.ModeResolution.Value,app.ModeResolution.Value,app.CoilradiusEditField.Value,app.my_rot);
                
            end
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function allocatememory(app)

            
            if app.coiltype == 1
                
                app.direction = app.direction .* app.vecscale;

            elseif app.coiltype == 2
            
                app.vecscale(1:app.my_gridsize^2,1) = ones;
                
            end
            
            app.A_modes(1:size(app.A_sup,1),1:3,size(app.lookup,1))=NaN;

        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function generatemodes(app)

            sups=size(app.A_sup,1);

            clear temp_support temp_A_sup temp_A_modes temp_directions normdist

            temp_support    = app.support;
            temp_A_sup      = app.A_sup;
            temp_A_modes    = app.A_modes;
            temp_direction  = app.direction;

            parfor i=1:sups
                normdist = repmat(myp2norm(temp_support,temp_A_sup(i,:)),1,3);
                temp_A_modes(i,:,:) = sum(temp_direction./normdist,1);
            end
            
            temp_A_modes = temp_A_modes * app.Mu0;
            app.A_modes = temp_A_modes;
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function app_data = SaveAppData(app)
                    % props = properties(app);  % Get all property names
                    % data = struct();  % Initialize struct
                    % 
                    % for i = 1:length(props)
                    %     try
                    %         data.(props{i}) = app.(props{i});  % Save each property
                    %     catch
                    %         warning('Skipping property: %s', props{i});  % Ignore unsupported properties
                    %     end
                    % end
                    % 
                    % save(filename, 'data');  % Save struct to file
                    % disp(['App data saved to ', filename]);
                   
                    ROIradiusEditFieldValueChanged(app);
                    ResolutionFieldValueChanged(app);

                    
                    props = properties(app);  % Get all property names
                    app_data = struct();  % Initialize struct

                     for i = 1:length(props)
                        try
                            value = app.(props{i});
                            % Only save properties that are numeric, logical, struct, or cell arrays
                            if isnumeric(value) || islogical(value) || isstruct(value) || iscell(value)
                                app_data.(props{i}) = value;  % Store valid properties
                            else
                                warning('Skipping UI or unsupported property: %s', props{i});
                            end
                        catch
                            warning('Error accessing property: %s', props{i});  % Ignore unsupported properties
                        end
                     end

                     disp('App data stored in app.data_app.');
               end
        % end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function getcorrelation(app)

            for mode1=1:size(app.lookup,1)
                    
                for mode2=mode1:size(app.lookup,1) %mode1 by 1
                    
                    app.corrA(mode1,mode2) = sum(((app.A_modes(:,1,mode1).*app.A_modes(:,1,mode2))...
                                                 +(app.A_modes(:,2,mode1).*app.A_modes(:,2,mode2))...
                                                 +(app.A_modes(:,3,mode1).*app.A_modes(:,3,mode2))), 'all');
                    
                end

            end

            app.corrA = triu(app.corrA,1)' + app.corrA;

            for mode1=1:size(app.lookup,1)
                
                app.CSU1(app.lookup(mode1,1),app.lookup(mode1,2)) = sum(((app.A_Aim(:,1).*app.A_modes(:,1,mode1))...
                                                                    +(app.A_Aim(:,2).*app.A_modes(:,2,mode1))...
                                                                    +(app.A_Aim(:,3).*app.A_modes(:,3,mode1))), 'all');
                                              
                app.CSUM(mode1,:)= app.CSU1(app.lookup(mode1,1),app.lookup(mode1,2));
                
            end
            
            app.CSUM(isnan(app.CSUM)) = 0;
            app.CSU1(isnan(app.CSU1)) = 0;

            app.CCSUM=app.corrA\app.CSUM;
            
            app.CSUMM(1:size(app.lookup,1),1:app.ModeResolution.Value)=zeros;

            if app.coiltype == 1 %spherical
            
                for i=1:app.ModeResolution.Value
                    
                    app.CSUMM(find([app.lookup(:,2)<=i] == 1),i)=app.corrA([app.lookup(:,2)<=i],[app.lookup(:,2)<=i])\app.CSUM([app.lookup(:,2)<=i]);
                    
                end

            elseif app.coiltype == 2 %planar

                for i=1:app.ModeResolution.Value
                    
                    app.CSUMM(reshape(app.lookupinv(1:i,1:i)',1,[])',i)=app.corrA(reshape(app.lookupinv(1:i,1:i)',1,[])',reshape(app.lookupinv(1:i,1:i)',1,[])')\app.CSUM(reshape(app.lookupinv(1:i,1:i)',1,[])');
                    
                end

            end

        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function cd_assembly(app)

            for i=1:app.ModeResolution.Value

                app.newcoils_cd(:,:,i) = sum(reshape((reshape(app.direction,[],size(app.CSUMM(:,i),1))'.*app.CSUMM(:,i))',size(app.direction,1),size(app.direction,2),[]),3);
                app.newcoils_A(:,:,i) = sum(reshape((reshape(app.A_modes,[],size(app.CSUMM(:,i),1))'.*app.CSUMM(:,i))',size(app.A_modes,1),size(app.A_modes,2),[]),3);
            end

        end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function average_coord = central_direction_cood(app, field)
            %weighted average of the coordinates
            field_binmap = any(field ~= 0, 2); % returns a binary map of all non-zero vectors

            scaled_supp = field_binmap .* (app.A_sup); % get the coordinates Nx3
            scaled_vec = field_binmap .* vecnorm(field,2,2); % get the magnitudes of the vectors Nx1

            total_field_mag = sum(scaled_vec);
            weighted_sup = (scaled_supp.*scaled_vec);
            average_coord = sum(scaled_supp.*scaled_vec,1)/total_field_mag;
            
        end


        function results = energy_in_field(app, field)
            results = sum(vecnorm(field,2,2),"all");
        end
       

        function magcorr = field_in_target(~,field, target_field)
            %Returns the total field magnitude in target region
             target_field_binmap = any(target_field ~= 0, 2);
             magcorr = sum((target_field_binmap .* vecnorm(field,2,2)), 'all');
        end
        
        function average_coord = central_direction(app, field)
            %weighted average of the coordinates
            field_binmap = any(field ~= 0, 2); % returns a binary map of all non-zero vectors

            scaled_supp = field_binmap .* (app.A_sup); % get the coordinates Nx3
            scaled_vec = field_binmap .* vecnorm(field,2,2); % get the magnitudes of the vectors Nx1

            total_field_mag = sum(scaled_vec);
            weighted_sup = (scaled_supp.*scaled_vec);
            average_coord = sum(scaled_supp.*scaled_vec,1)/total_field_mag;
        end
            

        %%
        function corr = calculate_corr(app, field, target_field)
            % For Optimisation Evaluation - Calculates the correlation
            % between two fields
            % corr1 = ((field .* target_field)); 
            % corr = sum(abs(corr1), 'all'); 

            corr = sum((target_field .* field), 'all');
            % target_field_binmap = any(target_field ~= 0, 2);
            % magcorr = sum((target_field .* norm(field)), 'all');
            % 
        % end
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


    end % for methods


    methods (Access = public)

        function push_A(app, dAdt, sup)
            app.A_Aim = dAdt;
            app.A_sup = sup;
            app.AFlag = 1;
            AMax(app)
            updateui(app)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.coiltype = 1;
            app.Mu0 = 1E-7;
            app.AFlag = 0;
            app.BFlag = 0;
            %% create preview sphere
            makesphere(app);
            %% create preview plane
            makeplane(app);

            updateui(app);
        end

        % Button pushed function: LoadCoilButton
        function LoadCoilButtonPushed(app, event)
            m_roi = app.ROIradiusEditField.Value;
            m_res = app.ResolutionField.Value;

            app.Coilapp = process_coil(app,m_roi,m_res);
        end

        % Button pushed function: LoadFieldButton
        function LoadFieldButtonPushed(app, event)
            m_roi = app.ROIradiusEditField.Value;
            m_res = app.ResolutionField.Value;

            app.Fieldapp = process_field(app,m_roi,m_res);
        end

        % Selection changed function: CoiltypeButtonGroup
        function CoiltypeButtonGroupSelectionChanged(app, event)

            if app.SphericalButton.Value == true
                app.coiltype = 1; % 1 for spherical
                updateui(app);
            elseif app.PlanarButton.Value == true
                app.coiltype = 2; % 2 for planar
                app.planarflag = 0;
                makeplane(app);
                updateui(app);
            end

            updateui(app);
            
        end

        % Value changed function: ROIradiusEditField
        function ROIradiusEditFieldValueChanged(app, event)
            app.roi_radius = app.ROIradiusEditField.Value;
            
        end

        % Value changed function: ResolutionField
        function ResolutionFieldValueChanged(app, event)
            app.resolution = app.ResolutionField.Value;
            
        end

        % Value changed function: CoilradiusEditField
        function CoilradiusEditFieldValueChanged(app, event)
           
            makesphere(app);
            updateui(app);
            
        end

        % Value changed function: ElevationSlider
        function ElevationSliderValueChanged(app, event)
            app.ElevationField.Value = app.ElevationSlider.Value;
            makeplane(app);
            updateui(app);
        end

        % Value changed function: AzimuthSlider
        function AzimuthSliderValueChanged(app, event)
            app.AzimuthField.Value = app.AzimuthSlider.Value;
            makeplane(app);
            updateui(app);   
        end

        % Value changed function: ElevationField
        function ElevationFieldValueChanged(app, event)
            app.ElevationSlider.Value = app.ElevationField.Value;
            makeplane(app);
            updateui(app);  
        end

        % Value changed function: AzimuthField
        function AzimuthFieldValueChanged(app, event)
            app.AzimuthSlider.Value = app.AzimuthField.Value;
            makeplane(app);
            updateui(app);              
        end

        % Button pushed function: FindbestplacementButton
        function FindbestplacementButtonPushed(app, event)
            % Incorrect code finding using the vector normal to target
            % surface
            % if app.AFlag == 1
            % 
            %     app.hotspot = app.A_sup(app.maxind,:)./norm(app.A_sup(app.maxind,:)); %gets the specific vector from the A_sup at index app.maxind, normalises it
            %     corr_vec = [0 0 1]; %reference vector along z-axis
            %     el_ang = acos(dot(corr_vec,app.hotspot)); % angle along the dot product between reference & hotspot // inclination of app.hotspot from z-axis
            %     R_1 = eul2rotm([el_ang 0 0],'XYZ'); % converts into rotation matrix about X-axis
            %     corr_vec_R1 =corr_vec * R_1; % rotates reference vector by this angle about X-axis
            %     %az_ang = acos(dot(corr_vec_R1,app.hotspot))* sign(app.hotspot(1)); %finds the angle between rotated angle and hotspot; the "sign" function only returns tells us whether azimuthal angle is +ve or -ve
            % 
            %     corr_vec_R1_xy = corr_vec_R1(1:2); % Only x and y components
            %     app_hotspot_xy = app.hotspot(1:2);
            %     corr_vec_R1_xy = corr_vec_R1_xy / norm(corr_vec_R1_xy);
            %     app_hotspot_xy = app_hotspot_xy / norm(app_hotspot_xy);
            %     az_ang = acos(dot(corr_vec_R1_xy, app_hotspot_xy)) * sign(app.hotspot(1));
            % 
            %     app.AzimuthField.Value = rad2deg(az_ang);
            %     app.AzimuthSlider.Value = rad2deg(az_ang);
            %     app.ElevationField.Value = rad2deg(el_ang);
            %     app.ElevationSlider.Value = rad2deg(el_ang);
            % 
            %     makeplane(app);
            %     updateui(app);
            % 
            % end

            if app.AFlag == 1

                app.hotspot = app.A_sup(app.maxind,:)./norm(app.A_sup(app.maxind,:));
                corr_vec = [0 0 1];
                el_ang = acos(dot(corr_vec,app.hotspot));
                R_1 = eul2rotm([el_ang 0 0],'XYZ');
                corr_vec_R1 =corr_vec * R_1;
                az_ang = acos(dot(corr_vec_R1,app.hotspot))* sign(app.hotspot(1));

                app.AzimuthField.Value = rad2deg(az_ang);
                app.AzimuthSlider.Value = rad2deg(az_ang);
                app.ElevationField.Value = rad2deg(el_ang);
                app.ElevationSlider.Value = rad2deg(el_ang);

                makeplane(app);
                updateui(app);

            end

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.coiltype = 2;
            generatecurrents(app);
            allocatememory(app);
            generatemodes(app);
            getcorrelation(app);
            cd_assembly(app);

            app.BFlag = 1;

            % File_Name_Value(app);
            % full_file_name = sprintf('%s_mode.mat', app.SavedFileNameEditField.Value);
            % app_data_mode = SaveAppData(app); %stores all the app. properties to app_data.
            % % save('14.02.2025_mode.mat','app_data_mode','-v7.3');
            % save(full_file_name,'app_data_mode','-v7.3');

            updateui(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            clear global
            delete(app)
        end

        % Value changed function: ShowCoilProjectionSurface
        function ShowCoilProjectionSurfaceValueChanged(app, event)
            updateui(app)
            
        end

        % Value changed function: ShowCDCheckBox
        function ShowCDCheckBoxValueChanged(app, event)
            updateui(app)
            
        end

        % Value changed function: PlanesizeEditField
        function PlanesizeEditFieldValueChanged(app, event)
            makeplane(app);
            updateui(app)
            
        end

        % Value changed function: ModeResolution
        function ModeResolutionValueChanged(app, event)
            if app.BFlag == 0

                app.Spinner.Limits(2) =         app.ModeResolution.Value;
                app.maxresSlider.Limits(2) =    app.ModeResolution.Value;
                app.maxresSlider.MajorTicks =   0:app.ModeResolution.Value;
                app.maxresSlider.Value =        app.ModeResolution.Value;
                app.Spinner.Value =             app.ModeResolution.Value;

            end
            
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.maxresSlider.Value = app.Spinner.Value;
            updateui(app);
            
        end

        % Value changed function: maxresSlider
        function maxresSliderValueChanged(app, event)
            value = app.maxresSlider.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
            % Override the selected value if you plan on using it within this function
            value = event.Source.MajorTicks(minIdx);
            
            app.Spinner.Value = value;
            updateui(app);
        end

        % Value changed function: ShowFieldCheckBox
        function ShowFieldCheckBoxValueChanged(app, event)
            updateui(app);
            
        end

        % Value changed function: ShowCoilCheckBox
        function ShowCoilCheckBoxValueChanged(app, event)
            updateui(app);
            
        end

        % Button pushed function: RunDiscretizationButton
        function RunDiscretizationButtonPushed(app, event)
        
            if isempty(app.hotspot)
                app.hotspot = app.A_sup(app.maxind,:)./norm(app.A_sup(app.maxind,:));
            end

            f = 0;
            if app.coiltype == 1 %spherical

                %if app.lastmaxval ~= app.Spinner.Value
                    for L = 1:app.Spinner.Value
                        for M = -L:L
                            f = f + app.CSUMM(app.lookupinv(app.ModeResolution.Value+1+M,L),app.Spinner.Value) * spherefun.sphharm(L,M);
                        end
                    end

                    my_curl = curl(f);
                    my_grad = grad(f);
               % end
    
                %%%
                my_dir(2,1:3) = zeros;
    
                start = app.hotspot';
                start = start./norm(start);
                i=1;
                coil(i,:) = start;
                coil2(i,:)= start;
                
                for i=2:app.ForwarddistEditField.Value
                    
                    my_del_curl = feval(my_curl,coil(i-1,1),coil(i-1,2),coil(i-1,3));
                    my_del_grad = feval(my_grad,coil(i-1,1),coil(i-1,2),coil(i-1,3));
                    my_del_grad = (my_del_grad/norm(my_del_grad))*app.spreadEditField.Value;
                    
                    my_dir(1,:) = (my_del_curl+my_del_grad)'/(app.CoilradiusEditField.Value*20);
                
                    coil(i,:) = coil(i-1,:) + my_dir(1,:) + my_dir(2,:)*app.discmassEditField.Value;
                    
                    coil(i,:) = coil(i,:) * 1/norm(coil(i,:));
                
                    my_dir(2,:) = my_dir(1,:);
                    
                end
    
                coil = coil .* app.CoilradiusEditField.Value;
    
                for i=2:app.BackwarddistEditField.Value
                    
                    my_del_curl = -feval(my_curl,coil2(i-1,1),coil2(i-1,2),coil2(i-1,3));
                    my_del_grad = -feval(my_grad,coil2(i-1,1),coil2(i-1,2),coil2(i-1,3));
                    my_del_grad = (my_del_grad/norm(my_del_grad))*app.spreadEditField.Value;
                    
                    my_dir(1,:) = (my_del_curl+my_del_grad)'/(app.CoilradiusEditField.Value*20);
                
                    coil2(i,:) = coil2(i-1,:) + my_dir(1,:) + my_dir(2,:)*app.discmassEditField.Value;
                    
                    coil2(i,:) = coil2(i,:) * 1/norm(coil2(i,:));
                
                    my_dir(2,:) = my_dir(1,:);
                    
                end
    
                coil2 = flip(coil2(2:end,:)) .* app.CoilradiusEditField.Value;
    
                app.discoil = [coil2; coil];
%%
            elseif app.coiltype == 2 %planar CD

                if app.planarflag ~= 2

                    syms x y l m ps %symbolic variables - indicates that they are variables; m & l are mode numbers of sinusoidal functions; ps is planar surface size
                    %SYMBOLIC FUNCTIONS
                    Y(x,y,l,m,ps) = (sin(l*(x+ps/2)*pi/ps)).*(sin(m*(y+ps/2)*pi/ps)); % basis function for the current distribution;
                    % Y(x,y,l,m,ps) = (sin(l*(x+ps/2)*pi/(2*ps))).*(sin(m*(y+ps/2)*pi/(2*ps)));

                    % Y_grad(x,y,l,m,ps) = [Y(x+0.5,y,l,m,ps)-Y(x-0.5,y,l,m,ps), Y(x,y+0.5,l,m,ps)-Y(x,y-0.5,l,m,ps), 0];
                    Y_grad(x,y,l,m,ps) = [Y(x-0.5,y,l,m,ps)-Y(x+0.5,y,l,m,ps), Y(x,y-0.5,l,m,ps)-Y(x,y+0.5,l,m,ps), 0]; % gradient of basis function - vector field
                    Y_curl(x,y,l,m,ps) = cross(Y_grad(x,y,l,m,ps), [0,0,1]); %curl of gradient field - curl field forms closed loops

                    %%check the curl - the second vector may be wrong

%                 end

                % symbolic functions are initialized
                f_curl(x,y) = x+y; %curl component
                f_curl(x,y) = 0; 
                f_grad(x,y) = x+y; %gradient component
                f_grad(x,y) = 0;
                f_cd(x,y) = x+y;
                f_cd(x,y) = 0;

%                 if app.lastmaxval ~= app.Spinner.Value

                    for m = 1:app.Spinner.Value % for each mode
                        for n = 1:app.Spinner.Value
                            % takes coefficients from CSUMM
                            %adds scaled contibutions of the curl/grad 
                            f_curl = f_curl + app.CSUMM(app.lookupinv(m,n),app.Spinner.Value) .* Y_curl(x,y,m,n,app.PlanesizeEditField.Value); %  corresponds to Jgrad components
                            f_grad = f_grad + app.CSUMM(app.lookupinv(m,n),app.Spinner.Value) .* Y_grad(x,y,m,n,app.PlanesizeEditField.Value); % corresponds to Jequiv 
                            f_cd = f_cd + app.CSUMM(app.lookupinv(m, n), app.Spinner.Value) * Y(x,y,m,n,app.PlanesizeEditField.Value);   
                            % f_curl = f_curl + app.opticoeff(app.lookupinv(m,n)) .* Y_curl(x,y,m,n,app.PlanesizeEditField.Value); %  corresponds to Jgrad components
                            % f_grad = f_grad + app.opticoeff(app.lookupinv(m,n)) .* Y_grad(x,y,m,n,app.PlanesizeEditField.Value); % corresponds to Jequiv 
                        
                        end
                    end

%                    fcurl(x,y) = symfun(f_curl,[x y]); 
%                    fgrad(x,y) = symfun(f_grad,[x y]);

% %% Plotting Grad of CD magnitude

% [X, Y] = meshgrid(linspace(-app.PlanesizeEditField.Value/2, app.PlanesizeEditField.Value/2, 50), linspace(-app.PlanesizeEditField.Value/2, app.PlanesizeEditField.Value/2, 50));
% % [X, Y] = meshgrid(linspace(-app.PlanesizeEditField.Value, app.PlanesizeEditField.Value, 50), linspace(-app.PlanesizeEditField.Value, app.PlanesizeEditField.Value, 50));
% 

% f_grad_x = zeros(size(X)); f_grad_y = zeros(size(X)); 
% for i = 1:numel(X)
%     f_eval = double(f_grad(X(i), Y(i))); % Evaluate f_grad at (x, y)
%     f_grad_x(i) = f_eval(1); %  x
%     f_grad_y(i) = f_eval(2); %  y
% end

% f_grad_mag = sqrt(f_grad_x.^2 + f_grad_y.^2); %magnitude
%
% figure;
% surf(X, Y, f_grad_mag, 'EdgeColor', 'none');
% title('Magnitude of f\_grad'); xlabel('x'); ylabel('y'); zlabel('|f\_grad|'); colorbar;
% % 
% % %  Plotting a seperate vector field
% % figure;
% % quiver(X, Y, f_grad_x, f_grad_y, 'AutoScale', 'on', 'LineWidth', 1.5);
% % title('Vector Field of f\_grad');
% % xlabel('x');
% % ylabel('y');
% % grid on;

%%
% %% Plotting the Grad of CD magnitude with signed directions
% 
% % [X, Y] = meshgrid(linspace(-app.PlanesizeEditField.Value/2, app.PlanesizeEditField.Value/2, 50), ...
%                   % linspace(-app.PlanesizeEditField.Value/2, app.PlanesizeEditField.Value/2, 50));
% [X, Y] = meshgrid(linspace(-app.PlanesizeEditField.Value, app.PlanesizeEditField.Value, 50), ...
%                   linspace(-app.PlanesizeEditField.Value, app.PlanesizeEditField.Value, 50));
% 

% f_grad_x = zeros(size(X)); 
% f_grad_y = zeros(size(X)); 
% for i = 1:numel(X)
%     f_eval = double(f_grad(X(i), Y(i))); % Evaluate f_grad at (x, y)
%     f_grad_x(i) = f_eval(1); % Extract x-component
%     f_grad_y(i) = f_eval(2); % Extract y-component
% end
% 
% f_grad_mag = sqrt(f_grad_x.^2 + f_grad_y.^2); %Get the magnitude
% 
% signed_f_grad_mag = f_grad_x .* f_grad_y; % Example: sign from x * y components
% 
% % Plot signed f_grad magnitude 
% figure;
% surf(X, Y, signed_f_grad_mag, 'EdgeColor', 'none'); title('Signed Magnitude of f\_grad');
% xlabel('x'); ylabel('y'); zlabel('Signed |f\_grad|'); colorbar;
% 
% % Use a diverging colormap
% colormap('jet'); % Alternatively, try 'redblue' or define your own diverging colormap
% caxis([-max(abs(signed_f_grad_mag(:))), max(abs(signed_f_grad_mag(:)))]); % Symmetric color axis
% 
% % Add vector field overlay
% hold on;
% quiver3(X, Y, zeros(size(X)), f_grad_x, f_grad_y, zeros(size(X)), 'k', 'LineWidth', 1);
% hold off;
% 
% % % Plot the regular vector field (optional)
% % figure;
% % quiver(X, Y, f_grad_x, f_grad_y, 'AutoScale', 'on', 'LineWidth', 1.5);
% % title('Vector Field of f\_grad');
% % xlabel('x'); ylabel('y');
% % grid on;

                end       
                app.planarflag = 1;
                my_dir(2,1:3) = zeros;
                start = [.1 .1 0];
                % start = [50 -40 0];
                i=1;
                coil(i,:) = start;
                coil2(i,:)= start;

                %generate forward path iteratively based on contributions from f_curl and f_grad
                    my_dir_mag = 2;
                for i=2:app.ForwarddistEditField.Value
                    
                    my_del_curl = double(f_curl(coil(i-1,1),coil(i-1,2))); %the jgrad component at start point
                    my_del_grad = double(f_grad(coil(i-1,1),coil(i-1,2))); % jequiv component at start point
                    my_del_grad = (my_del_grad)*(app.spreadEditField.Value);%*5;%*app.spreadEditField.Value; 
                    % my_del_curl = (my_del_curl/norm(my_del_curl))*(app.spreadEditField.Value);% k*[extra divergence term]
                    
               
                    my_dir(1,:) = (((my_del_curl+my_del_grad)/norm(my_del_curl)))*my_dir_mag;
                    % my_dir(1,:) = (my_del_curl+my_del_grad)*10; %/(app.CoilradiusEditField.Value); 
                    % my_dir(1,:) = (my_del_curl+my_del_grad)/(20*(app.CoilradiusEditField.Value));
                    % my_dir(1,:) = ((my_del_curl+my_del_grad)/norm((my_del_curl+my_del_grad)))/30;


                    % pos_scale = norm(coil(i-1,:)); % Scale based on distance from the origin
                    % my_dir_mag = 2 + 0.1 * pos_scale; % Increase movement as the coil moves outward

                    my_dir_magnitudes(i) = norm(my_dir(1,:)); % Magnitude of the vector
                    my_curl_magnitudes(i) = norm(my_del_curl(1,:)); % Magnitude of the vector
                    my_grad_magnitudes(i) = norm(my_del_grad(1,:));

                    % corresponds to tracing integral curve through
                    % superimposed field jres
                    coil(i,:) = coil(i-1,:) + my_dir(1,:) + my_dir(2,:)*app.discmassEditField.Value; % updates coil position = prev. position + direction + correction factor
                    % my_coil(i) = norm(coil(i,:));
                    % coil(i,:) = coil(i,:) * 1/norm(coil(i,:));
                    %my_dir(2,:) = 0.5*(my_dir(2,:) + my_dir(1,:));
                    my_dir(2,:) = my_dir(1,:);
                end

                % coil = coil .* app.PlanesizeEditField.Value/2;
                plot3(coil(:,1), coil(:,2), coil(:,3)); % Plot forward coil path

    %% For Debug of forward coil path: m_dir
                % % Plot the magnitudes of my_dir/my_curl/my_grad after the loop
                % figure;
                % plot(1:app.ForwarddistEditField.Value, my_dir_magnitudes, 'LineWidth', 2);
                % title('Magnitude of my\_dir during Forward Loop');
                % xlabel('Iteration');
                % ylabel('Magnitude of my\_dir');
                % grid on;
                % 
                % figure;
                % plot(1:app.ForwarddistEditField.Value, my_curl_magnitudes, 'LineWidth', 2);
                % title('Magnitude of my\_curl during Forward Loop');
                % xlabel('Iteration');
                % ylabel('Magnitude of my\_curl');
                % grid on;
                % 
                % figure;
                % plot(1:app.ForwarddistEditField.Value, my_grad_magnitudes, 'LineWidth', 2);
                % title('Magnitude of my\_grad during Forward Loop');
                % xlabel('Iteration');
                % ylabel('Magnitude of my\_grad');
                % grid on;
                % %
                % % Assuming my_dir_magnitudes, my_curl_magnitudes, and my_grad_magnitudes 
                % % are vectors of the same length
                % 
                % figure; %plot all on one plot
                % hold on;
                % plot(1:app.ForwarddistEditField.Value, my_dir_magnitudes, 'LineWidth', 2, 'DisplayName', 'my\_dir');
                % plot(1:app.ForwarddistEditField.Value, my_curl_magnitudes, 'LineWidth', 2, 'DisplayName', 'my\_curl');
                % plot(1:app.ForwarddistEditField.Value, my_grad_magnitudes, 'LineWidth', 2, 'DisplayName', 'my\_grad');
                % 
                % title('Magnitudes during Forward Loop');xlabel('Iteration');ylabel('Magnitude');legend show; grid on;
                % hold off;

%%
                my_dir(2,1:3) = zeros;

                % backwards path
                for i=2:app.BackwarddistEditField.Value
                    
                    my_del_curl = -double(f_curl(coil2(i-1,1),coil2(i-1,2)));
                    my_del_grad = -double(f_grad(coil2(i-1,1),coil2(i-1,2)));
                    my_del_grad = (my_del_grad)*app.spreadEditField.Value;
                    % my_del_curl = (my_del_curl/norm(my_del_curl))*app.spreadEditField.Value;
                    
                    my_dir_mag = 2;
                    my_dir(1,:) = ((my_del_curl+my_del_grad)/norm(my_del_curl))*my_dir_mag;
                    % my_dir(1,:) = (my_del_curl+my_del_grad)/(20*(app.CoilradiusEditField.Value));
                    % my_dir(1,:) = ((my_del_curl+my_del_grad)/norm((my_del_curl+my_del_grad)))/10;

                    coil2(i,:) = coil2(i-1,:) + my_dir(1,:) + my_dir(2,:)*app.discmassEditField.Value; %calculation of next coil position
                    
                    % coil2(i,:) = coil2(i,:) * 1/norm(coil2(i,:)); %
%                     NORMALISE THE CURRENT POSITION VECTOR
                    my_dir(2,:) = my_dir(1,:);
                end

                coil2 = flip(coil2(2:end,:));% .* app.PlanesizeEditField.Value/2; % scaled to planar size
                plot3(coil2(:,1), coil2(:,2), coil2(:,3)); % Plot backwards path

                app.discoil = [coil2; coil]; 
                plot3(app.discoil(:,1), app.discoil(:,2), app.discoil(:,3)); 
%% %% Reflection Code to map paths running off the defined plane back onto the plane (see Log 07/01/2025)
%                 x_limit = app.PlanesizeEditField.Value/2; y_limit = app.PlanesizeEditField.Value/2;
%                 x = app.discoil(:,1); y = app.discoil(:,2)
%                 x_reflected = x;
%                 y_reflected = y;

%                 % Reflect x-coordinates
%                 for i = 1:length(x)
%                     if x_reflected(i) > x_limit
%                         x_reflected(i) = 2 * x_limit - x_reflected(i);
%                     elseif x_reflected(i) < -x_limit
%                         x_reflected(i) = -2 * x_limit - x_reflected(i);
%                     end
%                 end
% 
%                 % Reflect y-coordinates
%                 for i = 1:length(y)
%                     if y_reflected(i) > y_limit
%                         y_reflected(i) = 2 * y_limit - y_reflected(i);
%                     elseif y_reflected(i) < -y_limit
%                         y_reflected(i) = -2 * y_limit - y_reflected(i);
%                     end
%                 end

%%
                % app.discoil(:,1) = x_reflected(:); app.discoil(:,2) = y_reflected(:);  % Path with reflected
                app.discoilor = app.discoil; % define as the coil without translation or rotation

                app.discoil = (app.discoil * app.my_rot) + app.my_coillift; % rotation & lift


                % % DEBUG - Plot translated + rotated coil
                figure;
                title('Generated Coil Path with rotation and offset');
                plot3(app.discoil(:, 1), app.discoil(:, 2), app.discoil(:, 3), 'LineWidth', 2);
                xlabel('x');ylabel('y'); zlabel('z');grid on;
                % %

%% Plot full coil path onto signed grad of CD plot
                % %
                         % Define grid size and range
                grid_size = 100; % Resolution of the grid
                ps = app.PlanesizeEditField.Value; % Planar surface size from your app
                
                % Create the grid
                x_range = linspace(-ps/2, ps/2, grid_size);
                y_range = linspace(-ps/2, ps/2, grid_size);
                [X, Y] = meshgrid(x_range, y_range);
                Z = double(f_cd(X, Y));

                figure;
                imagesc(x_range, y_range, Z);
                colormap(jet);
                colorbar;
                title('f_cd & Coil');
                xlabel('X-axis');
                ylabel('Y-axis');
                axis equal tight;
                hold on;

                plot(app.discoilor(:, 1), app.discoilor(:, 2), 'r', 'LineWidth', 1);
                grid on;
                hold off;
% % % % % 
            end

            app.lastmaxval = app.Spinner.Value;
            updateui(app);

        end

        % Button pushed function: RunOptimizationButton
        function RunOptimizationButtonPushed(app, event)
            %% Original
            % myresid = @(myccsum) sum((app.A_Aim - sum( repmat( reshape( myccsum(:),1,1,length(myccsum) ), size(app.A_modes,1), size(app.A_modes,2), 1).*app.A_modes, 3)).^2, "all");
            % myoptions = optimset('TolX', app.StepToleranceEditField.Value, 'TolFun', app.FunctionToleranceEditField.Value, 'maxfunevals', app.MaxFunEvalsEditField.Value, 'maxiter', app.MaxIterationsEditField.Value, 'disp', 'iter');
            % myinitvalue = app.CSUMM(:,app.maxresSlider.Value);
            % [app.opticoeff, myresidval, myexitflag, myoutput] = fmincon(myresid, myinitvalue, [], [], [], [], -Inf, Inf, [], myoptions);
            
            %% Required Functions & Starting Metrics
            field = @(myccsum) sum(repmat(reshape(myccsum(:), 1, 1, length(myccsum)),size(app.A_modes, 1), size(app.A_modes, 2), 1) .* app.A_modes, 3);

            orig_corr = calculate_corr(app, app.A_Aim, field(app.CSUMM(:,app.maxresSlider.Value)));
            fprintf('Original Correlation: %.2f J\n', orig_corr);

            orig_energy = energy_in_field(app, field(app.CSUMM(:,app.maxresSlider.Value)));
            fprintf('Original Energy: %.2f J\n', orig_energy);

            orig_field_in_ROI = field_in_target(app, field(app.CSUMM(:,app.maxresSlider.Value)), app.A_Aim);
            fprintf('Original Field_in_ROI: %.2f J\n', orig_field_in_ROI);

            orig_central_direction = central_direction_cood(app, app.A_Aim);
            fprintf('Original Central Direction: %.2f %.2f %.2f\n', orig_central_direction);

            %%
            target_energy = energy_in_field(app, app.A_Aim);
            fprintf('Target Energy: %.2f J\n', target_energy);

            target_central_direction = central_direction_cood(app, app.A_Aim);
            fprintf('Original Central Direction: %.2f %.2f %.2f\n', target_central_direction);

            %% 23/02/2025
            % Compute the weighted sum of modes
    %        myresid = @(myccsum) - ( ...
    % sum(app.A_Aim .* sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3), 'all') ...
    % ./ max( max(vecnorm(sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3), 2, 2) ...
    %            .* double(app.A_Aim == 0), [], 'all') , eps) ... 
    %            );


           %% From Goetz Meeting on 04/03/2025
           % line corresponding to A field . Target field
            % % sum(app.A_Aim .* sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3), 'all')

           % line corresponding to A field . Target field
           %max( max(vecnorm(sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3), 2, 2) .* double(app.A_Aim == 0), [], 'all') , eps) );


           % % Type 1 Objective Functions

           % p = 1; q = 1:
           % myresid = @(myccsum) - ( sum(abs(app.A_Aim .* sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3)), 'all') ./ sum(double(app.A_Aim == 0) .* sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3), 'all'));

           % p = 2; q = 1:
           % myresid = @(myccsum) - ( sum((app.A_Aim .* sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3)).^2, 'all') ./ sum(double(app.A_Aim == 0) .* sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3), 'all'));
           % myresid = @(myccsum) - ( sum((app.A_Aim .* sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3)).^2, 'all') ./ sum(abs(double(app.A_Aim == 0) .* sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3)), 'all'));

          % 13/05/2025 (Original Working)- myresid = @(myccsum) - ( sum((app.A_Aim .* sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3)).^2, 'all') ./ sum(double(app.A_Aim == 0) .* abs(sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3)), 'all'));

           %14/05/2025 - P2^2Q1
           % myresid = @(myccsum) - ((sum((app.A_Aim .* sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3)).^2, 'all'))^2 ./ sum(double(app.A_Aim == 0) .* abs(sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3)), 'all'));

           %14/05/2025 - P2Q1^1/2 The 
           % myresid = @(myccsum) - (sum((app.A_Aim .* sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3)).^2, 'all') ./ (sum(double(app.A_Aim == 0) .* abs(sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3)), 'all'))^(1/2));

            %14/05/2025 - P4Q1 - Numerator is elementally squared
          myresid = @(myccsum) - ( sum((app.A_Aim .* sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3)).^3, 'all') ./ sum(double(app.A_Aim == 0) .* abs(sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3)), 'all'));


           % p = 1; q = 2:
           % myresid = @(myccsum) - ( sum(app.A_Aim .* sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3), 'all') ./ ((sum(double(app.A_Aim == 0) .* (sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes).^2, 3), 'all'));
           %myresid = @(myccsum) - (sum(app.A_Aim .* sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3), 'all') ./ ((sum(double(app.A_Aim == 0) .* (sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes), 3).^2, 'all') ));

%            % myresid = @(myccsum) - ( ...
%     sum(abs(app.A_Aim .* sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3)), 'all') ./ ...
%     (sum(double(app.A_Aim == 0) .* (sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3)).^2, 'all')) ...
% );


           % p = 2; q = 2:
           % myresid = @(myccsum) - ( sum((app.A_Aim .* sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3)).^2, 'all') ./ sum(double(app.A_Aim == 0) .* (sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes), 3).^2, 'all'));
% myresid = @(myccsum) - ( sum((app.A_Aim .* sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3)).^2, 'all') ./ ((sum(double(app.A_Aim == 0) .* (sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes).^2, 3), 'all') );
           
           % p = 1; q = inf: (as in 23/02/2025)
           % myresid = @(myccsum) - ( sum(app.A_Aim .* sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3), 'all') ./ max( max(vecnorm(sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3), 2, 2) .* double(app.A_Aim == 0), [], 'all') , eps));


            %% Version 1

            % %Sum of energy in target/overall energy of approximated field
            % %%{squared sum of field with itself} - Version 1.2
            % % myresid = @(myccsum) - (sum((app.A_Aim .* sum(repmat(reshape(myccsum(:), 1, 1, length(myccsum)),size(app.A_modes, 1), size(app.A_modes, 2), 1) .* app.A_modes, 3)), 'all') / sum(sum(repmat(reshape(myccsum(:), 1, 1, length(myccsum)),size(app.A_modes, 1), size(app.A_modes, 2), 1) .* app.A_modes, 3).^2, 'all'));
            % 
            % % Version 1.1
            % myresid = @(myccsum) - (sum((app.A_Aim .* sum(repmat(reshape(myccsum(:), 1, 1, length(myccsum)),size(app.A_modes, 1), size(app.A_modes, 2), 1) .* app.A_modes, 3)), 'all') / sum(vecnorm(sum(repmat(reshape(myccsum(:), 1, 1, length(myccsum)),size(app.A_modes, 1), size(app.A_modes, 2), 1) .* app.A_modes, 3),2,2)) );

            % % Version 1.3
            % % myresid = @(myccsum) - (sum((app.A_Aim .* sum(repmat(reshape(myccsum(:), 1, 1, length(myccsum)),size(app.A_modes, 1), size(app.A_modes, 2), 1) .* app.A_modes, 3)), 'all') / (sum(vecnorm(sum(repmat(reshape(myccsum(:), 1, 1, length(myccsum)),size(app.A_modes, 1), size(app.A_modes, 2), 1) .* app.A_modes, 3),2,2)) )^2);
            % 
            % 
            % % Version 1.4
            % % myresid = @(myccsum) - ((sum((app.A_Aim .* sum(repmat(reshape(myccsum(:), 1, 1, length(myccsum)),size(app.A_modes, 1), size(app.A_modes, 2), 1) .* app.A_modes, 3)), 'all')^2) / (sum(vecnorm(sum(repmat(reshape(myccsum(:), 1, 1, length(myccsum)),size(app.A_modes, 1), size(app.A_modes, 2), 1) .* app.A_modes, 3),2,2)) ));
            % 
            % 
            % % Version 1.5
            % myresid = @(myccsum) - (sum((app.A_Aim .* sum(repmat(reshape(myccsum(:), 1, 1, length(myccsum)),size(app.A_modes, 1), size(app.A_modes, 2), 1) .* app.A_modes, 3)), 'all') / (sum(vecnorm(sum(repmat(reshape(myccsum(:), 1, 1, length(myccsum)),size(app.A_modes, 1), size(app.A_modes, 2), 1) .* app.A_modes, 3),2,2)) )^3);
            % % 
            % % 
            % % % sum(vecnorm(sum(repmat(reshape(myccsum(:), 1, 1, length(myccsum)),size(app.A_modes, 1), size(app.A_modes, 2), 1) .* app.A_modes, 3),2,2));
            % % 
            myoptions = optimset('TolX', app.StepToleranceEditField.Value, 'TolFun', app.FunctionToleranceEditField.Value, 'maxfunevals', app.MaxFunEvalsEditField.Value, 'maxiter', app.MaxIterationsEditField.Value, 'disp', 'iter');
            % myinitvalue = app.CSUMM(:,app.maxresSlider.Value);
            myinitvalue = app.CSUMM(:,10);


            % % [app.opticoeff, myresidval, myexitflag, myoutput] = fmincon(myresid, myinitvalue, [], [], [], [], -Inf, Inf, [], myoptions);
            % cmdOutput = evalc('[app.opticoeff, myresidval, myexitflag, myoutput] = fmincon(myresid, myinitvalue, [], [], [], [], -Inf, Inf, [], myoptions);');
            % 
            % % Save iteration output to a text file
            % fileID = fopen('07.03.2025_fmincon_iterations_CombTarg111_p1q2_400it1.txt', 'w');
            % fprintf(fileID, '%s', cmdOutput);
            % fclose(fileID);
%% 14/05/2025 -EE2
% Energy outside / Correlation inside target 1
            myresid = @(myccsum)  sum(double(app.A_Aim == 0) .* abs(sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3)), 'all')  ./  sum((app.A_Aim .* sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3)).^2, 'all');
       
          
% Energy outside / Correlation inside target 2 (EE2 V2)
            % % myresid = @(myccsum)  log( eps + sum(double(app.A_Aim == 0) .* abs(sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3)), 'all')  ./  sum((app.A_Aim .* sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3)).^3, 'all')) ;
       
%EE2 (V3)
            myresid = @(myccsum)  log( eps + sum(double(app.A_Aim == 0) .* abs(sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3)), 'all')  ./  (sum((app.A_Aim .* sum(reshape(myccsum(:), 1, 1, []) .* app.A_modes, 3)).^2, 'all')^2)) ;
       

%%


            % Set up diary to capture and display output
            % diary('08.03.2025_fmincon_iterations_CombTarg111_p1q2_infit1.txt'); % Start logging


            full_file_name_opt = sprintf('%s_fmincon_iterations.txt', app.SavedFileNameEditField.Value);
            diary(full_file_name_opt);
            diary on;
            
            % Run fmincon (output will be displayed in real time)
            [app.opticoeff, myresidval, myexitflag, myoutput] = fmincon(myresid, myinitvalue, [], [], [], [], -Inf, Inf, [], myoptions);
            
            % Stop logging and save output
            diary off;

            %%
            % %% Version 2
            % 
            %Sum of energy in target/overall energy of approximated field %{squared sum of field with itself}
            % alpha = 0.7;beta = 1-alpha;
            % myresid = @(myccsum) alpha*sum(sum(repmat(reshape(myccsum(:), 1, 1, length(myccsum)),size(app.A_modes, 1), size(app.A_modes, 2), 1) .* app.A_modes, 3).^2, 'all') - beta*sum((app.A_Aim .* sum(repmat(reshape(myccsum(:), 1, 1, length(myccsum)),size(app.A_modes, 1), size(app.A_modes, 2), 1) .* app.A_modes, 3)), 'all');
            % % 
            % myoptions = optimset('TolX', app.StepToleranceEditField.Value, 'TolFun', app.FunctionToleranceEditField.Value, 'maxfunevals', app.MaxFunEvalsEditField.Value, 'maxiter', app.MaxIterationsEditField.Value, 'disp', 'iter');
            % myinitvalue = app.CSUMM(:,app.maxresSlider.Value);
            % 
            % 
            %   [app.opticoeff, myresidval, myexitflag, myoutput] = fmincon(myresid, myinitvalue, [], [], [], [], -Inf, Inf, [], myoptions);

              %%
            % myresid = @(myccsum) sum((app.A_Aim - sum( repmat( reshape( myccsum(:),1,1,length(myccsum) ), size(app.A_modes,1), size(app.A_modes,2), 1).*app.A_modes, 3)).^2, "all");
            % myoptions = optimset('TolX', app.StepToleranceEditField.Value, 'TolFun', app.FunctionToleranceEditField.Value, 'maxfunevals', app.MaxFunEvalsEditField.Value, 'maxiter', app.MaxIterationsEditField.Value, 'disp', 'iter');
            % myinitvalue = app.CSUMM(:,app.maxresSlider.Value);
            % [app.opticoeff, myresidval, myexitflag, myoutput] = fmincon(myresid, myinitvalue, [], [], [], [], -Inf, Inf, [], myoptions);
            % 
            %%
            corr = calculate_corr(app, app.A_Aim, field(app.opticoeff));
            fprintf('Correlation: %.2f J\n', corr);

            % energy = calculate_corr(app, field(app.opticoeff), field(app.opticoeff));
            energy = energy_in_field(app, field(app.opticoeff));
            fprintf('Energy: %.2f J\n', energy);

            field_in_ROI = field_in_target(app, field(app.opticoeff), app.A_Aim);
            fprintf('Field_in_ROI: %.2f J\n', field_in_ROI);

            central_direction = central_direction_cood(app, field(app.opticoeff));
            fprintf('Central Direction: %.2f %.2f %.2f\n', central_direction);


            % File_Name_Value(app);
            % full_file_name = sprintf('%s_opt.mat', app.SavedFileNameEditField.Value);
            % app_data_opt = SaveAppData(app); %stores all the app. properties to app_data
            % save(full_file_name,'app_data_opt','-v7.3');


        end

        % Button pushed function: ExportCoilButton
        function ExportCoilButtonPushed(app, event)
            if isempty(app.discoil) ~= 1
                [myfilename, mypath]=uiputfile({'*.mat; *.xlsx; *.xls; *.csv','Supported coil wire path files (*.mat, *.xlsx, *.xls, *.csv)'; '*.mat','Matlab data files (*.mat)'; '*.xlsx; *.xls','Excel file (*.xlsx, *.xls)'; '*.csv','Comma-separated values (*.csv)'}, 'Select coil conductor path file ...');
                save([mypath myfilename],'-struct', 'app', 'discoil')
            end
        end

        % Button pushed function: PersonalisedFieldButton
        function Personalised_Field(app, event)
            m_roi = app.ROIradiusEditField.Value;
            m_res = app.ResolutionField.Value;
            
            app.Coilapp = personalised_field(app, m_roi, m_res);
        end

        % Button pushed function: LoadDataButton
        function Load_Data(app, event)
            m_roi = app.ROIradiusEditField.Value;
            m_res = app.ResolutionField.Value;

            %app.Coilapp = DataROI_field(app, m_roi, m_res);
            % app.Coilapp = Auto_DataROI_field(app, m_roi, m_res);
            app.Coilapp = Auto_DataROI_field3(app, m_roi, m_res);
        end

        % Button pushed function: ClosedLoopDiscretizationButton
        function Closed_Loop_Discretization(app, event)
            
            
                syms x y l m ps %symbolic variables - indicates that they are variables; m & l are mode numbers of sinusoidal functions; ps is planar surface size
                Y(x,y,l,m,ps) = (sin(l*(x+ps/2)*pi/ps)).*(sin(m*(y+ps/2)*pi/ps)); % basis function for the current distribution;
                Y_grad(x,y,l,m,ps) = [Y(x-0.5,y,l,m,ps)-Y(x+0.5,y,l,m,ps), Y(x,y-0.5,l,m,ps)-Y(x,y+0.5,l,m,ps), 0]; % gradient of basis function - vector field
                
                % symbolic functions are initialized
                f_cd(x,y) = x + y;
                f_cd(x,y) = 0;
                f_grad(x,y) = x+y; %gradient component
                f_grad(x,y) = 0;
    
                for m = 1:10
                    for n = 1:10
                        % Compute mode contribution using symbolic gradient
                        % f_cd = f_cd + app.CSUMM(app.lookupinv(m, n), app.Spinner.Value) * Y(x,y,m,n,app.PlanesizeEditField.Value);      
                        % p = app.lookupinv(m, n);
                        f_cd = f_cd + app.opticoeff(app.lookupinv(n, m), 1) * Y(x,y,m,n,app.PlanesizeEditField.Value);          
                    end
                end
                f_cd_numeric = matlabFunction(f_cd, 'Vars', [x, y]); %convert to Numerical Function - meant to be quicker; converting to double is also a numerical
            
        
                %greadient can't be a symbolic function directly
                % f_cd_grad = gradient(f_cd_numeric, [x, y]); % Gradient: [∂f_cd/∂x; ∂f_cd/∂y]
                %grad_at_point = subs(f_cd_grad, {x, y}, {1, 2}); % Example: x=1, y=2


                %% Closed Coil Paths as described by Paper 7
                % Find approx. min/max values of the stream function
                x_range = linspace(-app.PlanesizeEditField.Value/2, app.PlanesizeEditField.Value/2, 100); % X-axis
                y_range = linspace(-app.PlanesizeEditField.Value/2, app.PlanesizeEditField.Value/2, 100); % Y-axis
                [X, Y] = meshgrid(x_range, y_range); 
                Sr = f_cd_numeric(X, Y); %use same variables as paper

                Nw = 30; % No. of windings

                Sr_min = min(Sr(:));
                Sr_max = max(Sr(:));
                
                % levels in magnitude of Sr (stream funciton) for each
                % contour line
                Lk = Sr_min + ((1:Nw) - (1/2)) * (Sr_max - Sr_min) / Nw; % produces a vector of levels

                % levels = [Sr_min];
                % i = 1;
                % while i < Nw+1
                %     new_level = levels(i) + Lk;
                %     levels(i+1) = new_level;
                % end
                %%
                % Find contours with steps Lk
                C = contourc(x_range, y_range, Sr, Lk);
                
                % Iterate through  + find points in each contour line
                contours = {}; % array for paths for each Lk step
                index = 1;
                while index < size(C, 2)
                    level = C(1, index); % Current contour level
                    num_points = C(2, index); % Number of points in this contour
                    contour_points = C(:, index+1:index+num_points); % Extract contour points
                    contours{end+1} = struct('level', level, 'points', contour_points);
                    index = index + num_points + 1; % Move to the next contour
                end

                %% Plot
                figure;
                hold on;
                imagesc(x_range, y_range, Sr); % Plot the stream function as a heatmap
                colormap(jet);
                colorbar;
                for i = 1:length(contours)
                    contour_points = contours{i}.points;
                    plot(contour_points(1, :), contour_points(2, :), 'b', 'LineWidth', 0.8);
                end
                title('Stream Function & Equally Spaced Coil Windings using Optimised Coefficients');
                xlabel('X');
                ylabel('Y');
                axis equal;
                hold off;

            
                %% Save the key variables
                
                % data_app.maxnum = app.maxnum;
                % data_app.maxind = app.maxind;
                % data_app.plansize = app.PlanesizeEditField.Value; %plane size
                % data_app.opticoeff = app.opticoeff; % optimised coeff.
                % data_app.lookup = app.lookup;
                % data_app.lookupinv = app.lookupinv;
                % data_app.vecscale = app.vecscale;
                % data_app.A_modes = app.A_modes;
                % data_app.corrA = app.corrA;
                % data_app.CSUMM = app.CSUMM;
                % data_app.CSUI = app.CSU1;
                % data_app.CSUM = app.CSUM;
                % data_app.CCSUM = app.CCSUM;
                % data_app.opticoeff = app.opticoeff;
                % data_app.my_rot = app.my_rot;
                % data_app.my_coillift = app.my_coillift;
                % data_app.A_Aim = app.A_Aim;
                % data_app.roi_radius = app.roi_radius;
                % data_app.resolutions = app.resolution;
                % 
                % 
                % % save('closed_loop_data.mat','app' ,'planarflag', 'spinnerValue', 'plansize', 'opticoeff', 'lookupinv', 'CSUMM','contours','my_rot','my_coillift','A_Aim', 'roi_radius', 'resolutions');
                % save('test_run_saving.mat','data_app');

               %%
               % app_data = SaveAppData(app); %stores all the app. properties to app_data.
               % % save('12.02.2025_run.mat','app_data', 'contours','-v7.3');
               % save('14.02.2025_postcoil.mat','app_data', 'contours','-v7.3');

               File_Name_Value(app);
               full_file_name = sprintf('%s_coil.mat', app.SavedFileNameEditField.Value);
               app_data_coil = SaveAppData(app); %stores all the app. properties to app_data.
               % save('14.02.2025_mode.mat','app_data_mode','-v7.3');
               save(full_file_name,'app_data_coil','contours','-v7.3');


        end

        % Button pushed function: RunEntireScriptButton
        function Run_All(app, event)
            app.coiltype = 2;
          

            FindbestplacementButtonPushed(app, []);
            disp('placement completed');
            RunButtonPushed(app, []);
            disp('modes found');
            RunOptimizationButtonPushed(app, []);
            Closed_Loop_Discretization(app, []);
            disp('closed loop disc');

            
        end

        % Value changed function: SavedFileNameEditField
        function File_Name_Value(app, event)
            file_name = app.SavedFileNameEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LoadCoilButton = uibutton(app.UIFigure, 'push');
            app.LoadCoilButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCoilButtonPushed, true);
            app.LoadCoilButton.Tooltip = {'Click here to load and process coil conductor path data'};
            app.LoadCoilButton.Text = 'Load Coil';
            app.COMATOSELabel = uilabel(app.UIFigure);
            app.COMATOSELabel.Text = 'COMATOSE';
            app.CoilradiusEditFieldLabel = uilabel(app.UIFigure);
            app.CoilradiusEditFieldLabel.Text = 'Coilradius';
            app.CoilradiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoilradiusEditField.ValueChangedFcn = createCallbackFcn(app, @CoilradiusEditFieldValueChanged, true);
            app.CoilradiusEditField.Tooltip = {'Set either the radius of a spherical, or the distance to the center of the ROI of a planar coil.'};
            app.CoilradiusEditField.Value = 100;
            app.ROIradiusEditFieldLabel = uilabel(app.UIFigure);
            app.ROIradiusEditFieldLabel.Text = 'ROI radius';
            app.ROIradiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.ROIradiusEditField.ValueChangedFcn = createCallbackFcn(app, @ROIradiusEditFieldValueChanged, true);
            app.ROIradiusEditField.Tooltip = {'Radius of the sphere encompasing the region of interest'};
            app.ROIradiusEditField.Value = 100;
            app.ResolutionEditFieldLabel = uilabel(app.UIFigure);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionField = uieditfield(app.UIFigure, 'numeric');
            app.ResolutionField.Limits = [0 Inf];
            app.ResolutionField.ValueChangedFcn = createCallbackFcn(app, @ResolutionFieldValueChanged, true);
            app.ResolutionField.Tooltip = {'Grid spacing of the sample points in the region of interest'};
            app.ResolutionField.Value = 2;
            app.CoiltypeButtonGroup = uibuttongroup(app.UIFigure);
            app.CoiltypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CoiltypeButtonGroupSelectionChanged, true);
            app.CoiltypeButtonGroup.Tooltip = {'Choose between a spherical or planar coill projection surface'};
            app.CoiltypeButtonGroup.Title = 'Coiltype';
            app.PlanarButton = uiradiobutton(app.CoiltypeButtonGroup);
            app.PlanarButton.Text = 'Planar';
            app.PlanarButton.Value = true;
            app.SphericalButton = uiradiobutton(app.CoiltypeButtonGroup);
            app.SphericalButton.Text = 'Spherical';
            app.PlanaradjustmentsPanel = uipanel(app.UIFigure);
            app.PlanaradjustmentsPanel.Title = 'Planar adjustments';
            app.ElevationSliderLabel = uilabel(app.PlanaradjustmentsPanel);
            app.ElevationSliderLabel.Text = 'Elevation';
            app.ElevationSlider = uislider(app.PlanaradjustmentsPanel);
            app.ElevationSlider.Limits = [-90 90];
            app.ElevationSlider.ValueChangedFcn = createCallbackFcn(app, @ElevationSliderValueChanged, true);
            app.AzimuthSliderLabel = uilabel(app.PlanaradjustmentsPanel);
            app.AzimuthSliderLabel.Text = 'Azimuth';
            app.AzimuthSlider = uislider(app.PlanaradjustmentsPanel);
            app.AzimuthSlider.Limits = [-90 90];
            app.AzimuthSlider.ValueChangedFcn = createCallbackFcn(app, @AzimuthSliderValueChanged, true);
            app.FindbestplacementButton = uibutton(app.PlanaradjustmentsPanel, 'push');
            app.FindbestplacementButton.ButtonPushedFcn = createCallbackFcn(app, @FindbestplacementButtonPushed, true);
            app.FindbestplacementButton.Tooltip = {'Auto allign the planar surface.'};
            app.FindbestplacementButton.Text = 'Find best placement';
            app.ElevationField = uieditfield(app.PlanaradjustmentsPanel, 'numeric');
            app.ElevationField.ValueChangedFcn = createCallbackFcn(app, @ElevationFieldValueChanged, true);
            app.AzimuthField = uieditfield(app.PlanaradjustmentsPanel, 'numeric');
            app.AzimuthField.ValueChangedFcn = createCallbackFcn(app, @AzimuthFieldValueChanged, true);
            app.PlanesizeEditFieldLabel = uilabel(app.PlanaradjustmentsPanel);
            app.PlanesizeEditFieldLabel.Text = 'Plane size';
            app.PlanesizeEditField = uieditfield(app.PlanaradjustmentsPanel, 'numeric');
            app.PlanesizeEditField.Limits = [-400 400];
            app.PlanesizeEditField.ValueChangedFcn = createCallbackFcn(app, @PlanesizeEditFieldValueChanged, true);
            app.PlanesizeEditField.Tooltip = {'Select the overall side length of the planar coil projection surface'};
            app.PlanesizeEditField.Value = 200;
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            xlabel(app.UIAxes, 'x (mm)')
            ylabel(app.UIAxes, 'y (mm)')
            zlabel(app.UIAxes, 'z (mm)')
            app.UIAxes.XLim = [-150 150];
            app.UIAxes.YLim = [-150 150];
            app.UIAxes.ZLim = [-150 150];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [-150 0 520];
            app.UIAxes.XTickLabel = {'-150'; '0'; '150'};
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [-150 0 150];
            app.UIAxes.YTickLabel = {'-150'; '0'; '150'};
            app.UIAxes.ZTick = [-150 0 150];
            app.UIAxes.ZTickLabel = {'-150'; '0'; '150'};
            app.UIAxes_2 = uiaxes(app.Panel);
            xlabel(app.UIAxes_2, 'x (mm)')
            ylabel(app.UIAxes_2, 'y (mm)')
            zlabel(app.UIAxes_2, 'z (mm)')
            app.UIAxes_2.XLim = [-150 150];
            app.UIAxes_2.YLim = [-150 150];
            app.UIAxes_2.ZLim = [-150 150];
            app.UIAxes_2.XAxisLocation = 'origin';
            app.UIAxes_2.XTick = [-150 0 150];
            app.UIAxes_2.XTickLabel = {'-150'; '0'; '150'};
            app.UIAxes_2.YAxisLocation = 'origin';
            app.UIAxes_2.YTick = [-150 0 150];
            app.UIAxes_2.YTickLabel = {'-150'; '0'; '150'};
            app.UIAxes_2.ZTick = [-150 0 150];
            app.UIAxes_2.ZTickLabel = {'-150'; '0'; '150'};
            app.ShowCoilProjectionSurface = uicheckbox(app.Panel);
            app.ShowCoilProjectionSurface.ValueChangedFcn = createCallbackFcn(app, @ShowCoilProjectionSurfaceValueChanged, true);
            app.ShowCoilProjectionSurface.Text = 'Show Coil Projection Surface';
            app.ShowCoilProjectionSurface.Value = true;
            app.Panel_2 = uipanel(app.Panel);
            app.Panel_2.Tooltip = {'Choose the projection result up to a certain resolution before progressing. The results in current density and field will be displayed above'};
            app.Spinner = uispinner(app.Panel_2);
            app.Spinner.Limits = [0 Inf];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 10;
            app.maxresSliderLabel = uilabel(app.Panel_2);
            app.maxresSliderLabel.Text = 'maxres';
            app.maxresSlider = uislider(app.Panel_2);
            app.maxresSlider.Limits = [0 10];
            app.maxresSlider.MajorTicks = [0 1];
            app.maxresSlider.ValueChangedFcn = createCallbackFcn(app, @maxresSliderValueChanged, true);
            app.maxresSlider.MinorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5 5.2 5.4 5.6 5.8 6 6.2 6.4 6.6 6.8 7 7.2 7.4 7.6 7.8 8 8.2 8.4 8.6 8.8 9 9.2 9.4 9.6 9.8 10];
            app.maxresSlider.Value = 10;
            app.Panel_3 = uipanel(app.Panel);
            app.ShowCDCheckBox = uicheckbox(app.Panel_3);
            app.ShowCDCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCDCheckBoxValueChanged, true);
            app.ShowCDCheckBox.Text = 'Show CD';
            app.ShowCDCheckBox.Value = true;
            app.ShowFieldCheckBox = uicheckbox(app.Panel_3);
            app.ShowFieldCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowFieldCheckBoxValueChanged, true);
            app.ShowFieldCheckBox.Text = 'Show Field';
            app.ShowCoilCheckBox = uicheckbox(app.Panel_3);
            app.ShowCoilCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCoilCheckBoxValueChanged, true);
            app.ShowCoilCheckBox.Text = 'Show Coil';
            app.LoadFieldButton = uibutton(app.UIFigure, 'push');
            app.LoadFieldButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFieldButtonPushed, true);
            app.LoadFieldButton.Tooltip = {'Click here to load and process precalculated field data'};
            app.LoadFieldButton.Text = 'Load Field';
            app.ModeResolutionEditFieldLabel = uilabel(app.UIFigure);
            app.ModeResolutionEditFieldLabel.Text = 'Mode Resolution';
            app.ModeResolution = uieditfield(app.UIFigure, 'numeric');
            app.ModeResolution.Limits = [1 20];
            app.ModeResolution.RoundFractionalValues = 'on';
            app.ModeResolution.ValueDisplayFormat = '%11g';
            app.ModeResolution.ValueChangedFcn = createCallbackFcn(app, @ModeResolutionValueChanged, true);
            app.ModeResolution.Tooltip = {'Set the maximum mode resolution'};
            app.ModeResolution.Value = 10;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Click here to run the projection operation'};
            app.RunButton.Text = 'Run';
            app.COilMAtchingTOolboxforLabel = uilabel(app.UIFigure);
            app.COilMAtchingTOolboxforLabel.Text = {'COil MAtching TOolbox for magnetic'; 'Stimulation and other Electromagnetics'};
            app.ConductordiscretizationPanel = uipanel(app.UIFigure);
            app.ConductordiscretizationPanel.Tooltip = {'Discretize the current density into a coil conductor path.'};
            app.ConductordiscretizationPanel.Title = 'Conductor discretization';
            app.discmassEditFieldLabel = uilabel(app.ConductordiscretizationPanel);
            app.discmassEditFieldLabel.Text = 'discmass';
            app.discmassEditField = uieditfield(app.ConductordiscretizationPanel, 'numeric');
            app.spreadEditFieldLabel = uilabel(app.ConductordiscretizationPanel);
            app.spreadEditFieldLabel.Text = 'spread';
            app.spreadEditField = uieditfield(app.ConductordiscretizationPanel, 'numeric');
            app.RunDiscretizationButton = uibutton(app.ConductordiscretizationPanel, 'push');
            app.RunDiscretizationButton.ButtonPushedFcn = createCallbackFcn(app, @RunDiscretizationButtonPushed, true);
            app.RunDiscretizationButton.Text = 'Run Discretization';
            app.ForwarddistEditFieldLabel = uilabel(app.ConductordiscretizationPanel);
            app.ForwarddistEditFieldLabel.Text = 'Forward dist';
            app.ForwarddistEditField = uieditfield(app.ConductordiscretizationPanel, 'numeric');
            app.ForwarddistEditField.ValueDisplayFormat = '%.0f';
            app.ForwarddistEditField.Value = 1000;
            app.BackwarddistEditFieldLabel = uilabel(app.ConductordiscretizationPanel);
            app.BackwarddistEditFieldLabel.Text = 'Backward dist';
            app.BackwarddistEditField = uieditfield(app.ConductordiscretizationPanel, 'numeric');
            app.BackwarddistEditField.ValueDisplayFormat = '%.0f';
            app.BackwarddistEditField.Value = 1000;
            app.ExportCoilButton = uibutton(app.ConductordiscretizationPanel, 'push');
            app.ExportCoilButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCoilButtonPushed, true);
            app.ExportCoilButton.Text = 'Export Coil';
            app.OptimizationPanel = uipanel(app.UIFigure);
            app.OptimizationPanel.Tooltip = {'(Optional) Run an optimization based on the results of the projection'};
            app.OptimizationPanel.Title = 'Optimization';
            app.RunOptimizationButton = uibutton(app.OptimizationPanel, 'push');
            app.RunOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @RunOptimizationButtonPushed, true);
            app.RunOptimizationButton.Text = 'Run Optimization';
            app.StepToleranceEditFieldLabel = uilabel(app.OptimizationPanel);
            app.StepToleranceEditFieldLabel.Text = 'Step Tolerance';
            app.StepToleranceEditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.StepToleranceEditField.Value = 1e-13;
            app.FunctionToleranceEditFieldLabel = uilabel(app.OptimizationPanel);
            app.FunctionToleranceEditFieldLabel.Text = 'Function Tolerance';
            app.FunctionToleranceEditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.FunctionToleranceEditField.Value = 1e-12;
            app.MaxFunEvalsEditFieldLabel = uilabel(app.OptimizationPanel);
            app.MaxFunEvalsEditFieldLabel.Text = 'Max Fun Evals';
            app.MaxFunEvalsEditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.MaxFunEvalsEditField.Value = 100000;
            app.MaxIterationsEditFieldLabel = uilabel(app.OptimizationPanel);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.MaxIterationsEditField.Value = 500;
            app.PersonalisedFieldButton = uibutton(app.UIFigure, 'push');
            app.PersonalisedFieldButton.ButtonPushedFcn = createCallbackFcn(app, @Personalised_Field, true);
            app.PersonalisedFieldButton.Text = 'Personalised Field';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @Load_Data, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ClosedLoopDiscretizationButton = uibutton(app.UIFigure, 'push');
            app.ClosedLoopDiscretizationButton.ButtonPushedFcn = createCallbackFcn(app, @Closed_Loop_Discretization, true);
            app.ClosedLoopDiscretizationButton.Text = 'Closed Loop Discretization';
            app.RunEntireScriptButton = uibutton(app.UIFigure, 'push');
            app.RunEntireScriptButton.ButtonPushedFcn = createCallbackFcn(app, @Run_All, true);
            app.RunEntireScriptButton.Text = 'Run Entire Script';
            app.SavedFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.SavedFileNameEditFieldLabel.Text = 'Saved File Name';
            app.SavedFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.SavedFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @File_Name_Value, true);
            app.SavedFileNameEditField.Value = '13.05.2025_COM111_10M_300P_p2^2q1_ROI100_EE_2000iter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Current_NEW_comatose_v01_Optimisation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end