classdef personalised_field < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        phi_minEditField         matlab.ui.control.EditField
        phi_minEditFieldLabel    matlab.ui.control.Label
        phi_maxEditField         matlab.ui.control.EditField
        phi_maxEditFieldLabel    matlab.ui.control.Label
        theta_maxEditField       matlab.ui.control.EditField
        theta_maxEditFieldLabel  matlab.ui.control.Label
        theta_minEditField       matlab.ui.control.EditField
        theta_minEditFieldLabel  matlab.ui.control.Label
        R_maxEditField           matlab.ui.control.EditField
        R_maxEditFieldLabel      matlab.ui.control.Label
        R_minEditField           matlab.ui.control.EditField
        R_minEditFieldLabel      matlab.ui.control.Label
        FinishandBackButton      matlab.ui.control.Button
        CalculateFieldButton     matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

%%
%   TMS KoDeIn Box, Version 1.0, 12/2022
%   Kaiserslautern Coil Design and Instrument Box
%   Max Koehler and Stefan M. Goetz
%   (c) 2006-2022


    properties (Access = public)
        coil
        coilraw
        dAdtcoil
        vol
        sup
        AFlag
        r_min
        r_max
        theta_min
        theta_max
        phi_min
        phi_max

        MainApp
        res
        roi
    end
    
    methods (Access = private)
%%  Function Update User Interface
        function updateui(app)
            hold (app.UIAxes,'off')
            
            if app.AFlag ==1

                hold (app.UIAxes,'on')
                
                %// Get the current colormap
                currentColormap = colormap(flipud(hot));
                close(gcf)
                n=10000;
                q=quiver3(app.UIAxes,app.sup(:,1:n:end),app.sup(:,2:n:end),app.sup(:,3:n:end),app.vol(:,1:n:end),app.vol(:,2:n:end),app.vol(:,3:n:end),2);
            
                %// Compute the magnitude of the vectors - q.UData r the
                %components of vectors plotted converted to column vectors
                %vectors concatenated into N x 3 matrix
                mags = sqrt(sum(cat(2, q.UData(:), q.VData(:), ...
                        reshape(q.WData, numel(q.UData), [])).^2, 2));
            
                %// Now determine the color to make each arrow using a colormap
                [~, ~, ind] = histcounts(mags, size(currentColormap, 1)); %bins the mags into intervals based on no. of colors available in currentColormap
                % ind provides color index for each vector based on
                % magnitude
            
                %// Now map this to a colormap to get RGB
                cmap = uint8(ind2rgb(ind(:), currentColormap) * 255); %convert ind into RGB color
                cmap(:,:,4) = 128; %sets transparancy [0:255]
                cmap = permute(repmat(cmap, [1 3 1]), [2 1 3]);
            
                %// We repeat each color 3 times (using 1:3 below) because each arrow has 3 vertices
                set(q.Head, ...
                    'ColorBinding', 'interpolated', ...
                    'ColorData', reshape(cmap(1:3,:,:), [], 4).');   %'
            
                %// We repeat each color 2 times (using 1:2 below) because each tail has 2 vertices
                set(q.Tail, ...
                    'ColorBinding', 'interpolated', ...
                    'ColorData', reshape(cmap(1:2,:,:), [], 4).');

                hold (app.UIAxes,'off')
               % app.UIAxes.Visible = 'on';
                     

            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, m_roi, m_res)
            app.res = m_res;
            app.roi = m_roi;

            app.MainApp = mainapp;
        end

        % Button pushed function: CalculateFieldButton
        function CalculateFieldButtonPushed(app, event)
            %app.roi - radius of ROI
            %app.res - resolution of grid (smallest distance between grid points)

            %introduce some more global variables 
            r_min = eval(app.R_minEditField.Value);% Minimum radius
            r_max = eval(app.R_maxEditField.Value);  % Maximum radius
            theta_min = eval(app.theta_minEditField.Value);  % Minimum azimuth (rad) - [-pi, pi]
            theta_max = eval(app.theta_maxEditField.Value);   % Maximum azimuth (rad)
            phi_min = eval(app.phi_minEditField.Value);    % Minimum elevation (rad)-[-pi/2,pi/2]
            phi_max = eval(app.phi_maxEditField.Value);     % Maximum elevation (rad)

            gridpoints = (((app.roi*2)/app.res)+1); %no. of grid points in one direction (diameter / resolution)

            [xx,yy,zz]= meshgrid(linspace(-app.roi,app.roi,gridpoints),linspace(-app.roi,app.roi,gridpoints),linspace(-app.roi,app.roi,gridpoints));
            %3D grid of coordinates, spanning -roi to +roi -- calculated
            %   from simple linspace (diameter divided into spaces of res)

            %xx, yy, zz reshaped into columns of app.sup
            app.sup(:,1)=reshape(xx,[],1);
            app.sup(:,2)=reshape(yy,[],1);
            app.sup(:,3)=reshape(zz,[],1);

            no = vecnorm(app.sup,2,2);
            app.sup(no>app.roi,:) =  []; %remove those points outside of ROI

            % Convert Cartesian coordinates to spherical coordinates
            [azimuth, elevation, radius] = cart2sph(app.sup(:, 1), app.sup(:, 2), app.sup(:, 3));

            % %Define the spherical region by radius, azimuth, and elevation
            % app.r_min = 0;  % Minimum radius
            % app.r_max = 150;  % Maximum radius
            % app.theta_min = -pi/2;  % Minimum azimuth (rad) - [-pi, pi]
            % app.theta_max = pi/2;   % Maximum azimuth (rad)
            % app.phi_min = -pi/2;    % Minimum elevation (rad)-[-pi/2,pi/2]
            % app.phi_max = pi/2;     % Maximum elevation (rad)


            % Filter points within the specified spherical region
            %region_mask = (radius >= r_min) & (radius <= r_max) & (azimuth >= theta_min) & (azimuth <= theta_max) & (elevation >= phi_min) & (elevation <= phi_max);
            region_mask = zeros(length(radius),1);  % Create a logical array of the same size as radius

            for i = 1:length(radius);
                if ((radius(i) >= r_min) & (radius(i) <= r_max) & (azimuth(i) >= theta_min) & (azimuth(i) <= theta_max) & (elevation(i)>= phi_min) & (elevation(i) <= phi_max));
              % Check if the radius meets the condition
                    region_mask(i) = 1;  % Set the corresponding mask value to true
                end
            end

            app.vol = zeros(size(app.sup));  %app.sup is the coordinates

            app.vol(~logical(region_mask),:) = 0
            %app.vol(logical(region_mask),:) = 2

%%
            % a = 1;   % Lower bound for random values
            % b = 100;  % Upper bound for random values
            % 
            % % Replace with random values within the specified range
            % % app.vol(logical(region_mask), :) = a + (b - a) * rand(sum(region_mask), size(app.vol, 2));
            % 
            % % my_array(logical(region_mask), 1:3) = repmat([-.6450,-.5803,-0.4972], sum(region_mask(:)), 1);
            % % app.vol(logical(region_mask), 1:3) =  my_array;%[-.6450,-.5803,-0.4972];%0.3e-4;% 0.1e-4;
            % % app.vol(logical(region_mask), 1:3) =  repmat([-.6450,-.5803,-0.4972], sum(region_mask(:)), 1);
            % app.vol(logical(region_mask), 1:3) =  repmat([.6450,.5803,0.4972], sum(region_mask(:)), 1);
%%
            a = 1;   % Lower bound for random values
            b = 100;  % Upper bound for random values

            randomf = a + (b - a) * rand(sum(region_mask(:)), 1);
            def_unit_vector = [.6450, .5803, 0.4972];

            app.vol(logical(region_mask), 1:3) = randomf .* repmat(def_unit_vector, sum(region_mask(:)), 1);


%%


            Mu0=            1E-7;           % Magnetic field constant/4*pi
            preconst=       Mu0;

            %progress dialog displayed while calculations occuring
            d = uiprogressdlg(app.UIFigure,'Icon','membrane.png', 'Title','Please Wait','Message','Calculating A-Field');
            d.Value = 0;

            app.AFlag=1; %indicates calculations stopped
            close(d)
            updateui(app);
        end

        % Button pushed function: FinishandBackButton
        function FinishandBackButtonPushed(app, event)
            % push field back to main app and close (check if field
            % calculation was successfull. If not, close with warning.
            if app.AFlag == 1
                push_A(app.MainApp, app.vol, app.sup)
                delete(app)
            end

        end

        % Value changed function: R_minEditField
        function Rmin_value_changed(app, event)
            app.r_min = app.R_minEditField.Value;
            
        end

        % Value changed function: R_maxEditField
        function Rmax_value_changed(app, event)
            app.r_max = app.R_maxEditField.Value;
            
        end

        % Value changed function: theta_minEditField
        function theta_min_variable(app, event)
            app.theta_min = app.theta_minEditField.Value;
            
        end

        % Value changed function: theta_maxEditField
        function theta_max_variable(app, event)
            app.theta_max = app.theta_maxEditField.Value;
            
        end

        % Value changed function: phi_minEditField
        function phi_min_variable(app, event)
            app.phi_min = app.phi_minEditField.Value;
            
        end

        % Value changed function: phi_maxEditField
        function phi_max_variable(app, event)
            app.phi_max = app.phi_maxEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'x (mm)')
            ylabel(app.UIAxes, 'y (mm)')
            zlabel(app.UIAxes, 'z (mm)')
            app.UIAxes.XLim = [-200 200];
            app.UIAxes.YLim = [-200 200];
            app.UIAxes.ZLim = [-85 210];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [-200 0 200];
            app.UIAxes.XTickLabel = {'-200'; '0'; '200'};
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [-200 0 200];
            app.UIAxes.YTickLabel = {'-200'; '0'; '200'};
            app.UIAxes.ZTick = [-85 0 210];
            app.UIAxes.ZTickLabel = {'-85'; '0'; '210'};
            app.CalculateFieldButton = uibutton(app.UIFigure, 'push');
            app.CalculateFieldButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFieldButtonPushed, true);
            app.CalculateFieldButton.Tooltip = {'Calculate the magnetic vector potential A for the coil path. Calculations might take a while. The results will be displayed on the right.'};
            app.CalculateFieldButton.Text = 'Calculate Field';
            app.FinishandBackButton = uibutton(app.UIFigure, 'push');
            app.FinishandBackButton.ButtonPushedFcn = createCallbackFcn(app, @FinishandBackButtonPushed, true);
            app.FinishandBackButton.Tooltip = {''};
            app.FinishandBackButton.Text = 'Finish and Back!';
            app.R_minEditFieldLabel = uilabel(app.UIFigure);
            app.R_minEditFieldLabel.Text = 'R_min';
            app.R_minEditField = uieditfield(app.UIFigure, 'text');
            app.R_minEditField.ValueChangedFcn = createCallbackFcn(app, @Rmin_value_changed, true);
            app.R_minEditField.Tooltip = {'Spacing of sample points of the coil path representing the resolution of the coil description.'};
            app.R_minEditField.Value = '40';
            app.R_maxEditFieldLabel = uilabel(app.UIFigure);
            app.R_maxEditFieldLabel.Text = 'R_max';
            app.R_maxEditField = uieditfield(app.UIFigure, 'text');
            app.R_maxEditField.ValueChangedFcn = createCallbackFcn(app, @Rmax_value_changed, true);
            app.R_maxEditField.Value = '85';
            app.theta_minEditFieldLabel = uilabel(app.UIFigure);
            app.theta_minEditFieldLabel.Text = 'theta_min';
            app.theta_minEditField = uieditfield(app.UIFigure, 'text');
            app.theta_minEditField.ValueChangedFcn = createCallbackFcn(app, @theta_min_variable, true);
            app.theta_minEditField.Tooltip = {'Magnetic inductance of the coil (without any cable or connectors attached). Most TMS devices expect around 10 µH.'};
            app.theta_minEditField.Value = '-pi/2';
            app.theta_maxEditFieldLabel = uilabel(app.UIFigure);
            app.theta_maxEditFieldLabel.Text = 'theta_max';
            app.theta_maxEditField = uieditfield(app.UIFigure, 'text');
            app.theta_maxEditField.ValueChangedFcn = createCallbackFcn(app, @theta_max_variable, true);
            app.theta_maxEditField.Tooltip = {'Required length of the conductor for the coil only if the coil is to be implemented (ignoring the coil cable).'};
            app.theta_maxEditField.Value = '-pi/6';
            app.phi_maxEditFieldLabel = uilabel(app.UIFigure);
            app.phi_maxEditFieldLabel.Text = 'phi_max';
            app.phi_maxEditField = uieditfield(app.UIFigure, 'text');
            app.phi_maxEditField.ValueChangedFcn = createCallbackFcn(app, @phi_max_variable, true);
            app.phi_maxEditField.Tooltip = {'Required length of the conductor for the coil only if the coil is to be implemented (ignoring the coil cable).'};
            app.phi_maxEditField.Value = '+pi/3';
            app.phi_minEditFieldLabel = uilabel(app.UIFigure);
            app.phi_minEditFieldLabel.Text = 'phi_min';
            app.phi_minEditField = uieditfield(app.UIFigure, 'text');
            app.phi_minEditField.ValueChangedFcn = createCallbackFcn(app, @phi_min_variable, true);
            app.phi_minEditField.Tooltip = {'Required length of the conductor for the coil only if the coil is to be implemented (ignoring the coil cable).'};
            app.phi_minEditField.Value = '+pi/4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = personalised_field(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end