classdef OpeningVoonData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        xField           matlab.ui.control.NumericEditField
        yField           matlab.ui.control.NumericEditField
        zField           matlab.ui.control.NumericEditField
        zSlider          matlab.ui.control.Slider
        zSliderLabel     matlab.ui.control.Label
        ySlider          matlab.ui.control.Slider
        ySliderLabel     matlab.ui.control.Label
        xSlider          matlab.ui.control.Slider
        xSliderLabel     matlab.ui.control.Label
        FinishButton     matlab.ui.control.Button
        LoadNiftiButton  matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        MainApp
        res
        roi

        hdr
        xx
        yy
        zz
        mainxx
        mainyy
        mainzz
        sup
        vol
        A_Aim
        AFlag
    end
    
    methods (Access = private)
        
        function updateui(app)

            %// Get the current colormap
            currentColormap = colormap(hot);
            close(gcf)
            n=100;
            q=quiver3(app.UIAxes,app.sup(:,1:n:end),app.sup(:,2:n:end),app.sup(:,3:n:end),app.A_Aim(:,1:n:end),app.A_Aim(:,2:n:end),app.A_Aim(:,3:n:end),2);
        
            %// Compute the magnitude of the vectors
            mags = sqrt(sum(cat(2, q.UData(:), q.VData(:), ...
                    reshape(q.WData, numel(q.UData), [])).^2, 2));
        
            %// Now determine the color to make each arrow using a colormap
            [~, ~, ind] = histcounts(mags, size(currentColormap, 1));
        
            %// Now map this to a colormap to get RGB
            cmap = uint8(ind2rgb(ind(:), currentColormap) * 255);
            cmap(:,:,4) = 255;
            cmap = permute(repmat(cmap, [1 3 1]), [2 1 3]);
        
            %// We repeat each color 3 times (using 1:3 below) because each arrow has 3 vertices
            set(q.Head, ...
                'ColorBinding', 'interpolated', ...
                'ColorData', reshape(cmap(1:3,:,:), [], 4).');   %'
        
            %// We repeat each color 2 times (using 1:2 below) because each tail has 2 vertices
            set(q.Tail, ...
                'ColorBinding', 'interpolated', ...
                'ColorData', reshape(cmap(1:2,:,:), [], 4).');

            hold (app.UIAxes,'off')


        function mod_field(app)
            % 3D interpolation on the volume data
            % interp3 - returns interpolated values of a function of 3
            % variables at specific poitns using linear interpolation
            vol_scale(:,:,:,1)=interp3(app.xx+app.xSlider.Value,app.yy+app.ySlider.Value,app.zz+app.zSlider.Value,app.hdr.vol(:,:,:,1),app.mainxx,app.mainyy,app.mainzz,'linear');
            vol_scale(:,:,:,2)=interp3(app.xx+app.xSlider.Value,app.yy+app.ySlider.Value,app.zz+app.zSlider.Value,app.hdr.vol(:,:,:,2),app.mainxx,app.mainyy,app.mainzz,'linear');
            vol_scale(:,:,:,3)=interp3(app.xx+app.xSlider.Value,app.yy+app.ySlider.Value,app.zz+app.zSlider.Value,app.hdr.vol(:,:,:,3),app.mainxx,app.mainyy,app.mainzz,'linear');

%             my_support(:,1) = reshape(app.xx,[],1);
%             my_support(:,2) = reshape(app.yy,[],1);
%             my_support(:,3) = reshape(app.zz,[],1);

            my_support(:,1) = reshape(app.mainxx,[],1);
            my_support(:,2) = reshape(app.mainyy,[],1);
            my_support(:,3) = reshape(app.mainzz,[],1);

%             my_support(:,1) = my_support(:,1) + repmat(app.xSlider.Value,size(my_support,1),1);
%             my_support(:,2) = my_support(:,2) + repmat(app.ySlider.Value,size(my_support,1),1);
%             my_support(:,3) = my_support(:,3) + repmat(app.zSlider.Value,size(my_support,1),1);

%             my_A_Aim(:,1) = reshape(app.hdr.vol(:,:,:,1),[],1);
%             my_A_Aim(:,2) = reshape(app.hdr.vol(:,:,:,2),[],1);
%             my_A_Aim(:,3) = reshape(app.hdr.vol(:,:,:,3),[],1);

            my_A_Aim(:,1) = reshape(vol_scale(:,:,:,1),[],1);
            my_A_Aim(:,2) = reshape(vol_scale(:,:,:,2),[],1);
            my_A_Aim(:,3) = reshape(vol_scale(:,:,:,3),[],1);

            no = vecnorm(my_support,2,2);
            my_support(no>app.roi,:) =  [];
            my_A_Aim(no>app.roi,:) =  [];
            app.sup = my_support;
            app.A_Aim = my_A_Aim;

            app.AFlag = 1;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, m_roi, m_res)
            app.res = m_res;
            app.roi = m_roi;

            app.MainApp = mainapp;
        end

        % Button pushed function: LoadNiftiButton
        function LoadNiftiButtonPushed(app, event)

            addpath('simnibs_functions'); % add simnib functions for opening nifti
            % open UI to select file
            [myfilename, mypath]=uigetfile({'*.mat; *.nii','Supported field files (*.mat, *.nii)'; '*.mat','Matlab data files (*.mat)'; '*.nii','NIfTI file (*.nii)'}, 'Select field file ...');
            
            if (~isequal(myfilename, 0)) && (~isequal(mypath, 0))    % both 0 means user clicked on cancel
            
                % check format
                if strfind(myfilename(length(myfilename)-3:length(myfilename)), '.mat')
                    % Matlab .mat file
                
                    app.fieldraw=importdata([mypath myfilename]);          
                
                elseif strfind(myfilename(length(myfilename)-3:length(myfilename)), '.nii')
                    % NIfTI file
                    oldFolder = cd;
                    cd (mypath); % changes to file directory 
                    app.hdr = nifti_load(myfilename);
                    cd (oldFolder);

                    app.hdr.sform(3,4)=-85; %resets z-axis offset (may not need)

                    [app.xx,app.yy,app.zz]=meshgrid(linspace(-((app.hdr.dim(2)-1)*app.hdr.sform(1,1)+app.hdr.sform(1,4)),(app.hdr.dim(2)-1)*app.hdr.sform(1,1)+app.hdr.sform(1,4),app.hdr.dim(2)),...
                                                    linspace(-((app.hdr.dim(3)-1)*app.hdr.sform(2,2)+app.hdr.sform(2,4)),(app.hdr.dim(3)-1)*app.hdr.sform(2,2)+app.hdr.sform(2,4),app.hdr.dim(3)),...
                                                    linspace((app.hdr.dim(4)-1)*app.hdr.sform(3,3)+app.hdr.sform(3,4),app.hdr.sform(3,4),app.hdr.dim(4)));
            
                    app.hdr.sform(3,4)=0;
                    
                else
                    % unknown file type
                    msgbox('Unknown file type.', 'Field file', 'error')
                    return
                end
            end
            
            gridpoints = ((app.roi*2)/app.res)+1;
            [app.mainxx,app.mainyy,app.mainzz]= meshgrid(linspace(-app.roi,app.roi,gridpoints),linspace(-app.roi,app.roi,gridpoints),linspace(-app.roi,app.roi,gridpoints));

            clear oldFolder myfilename mypath
            
            mod_field(app);
            updateui(app);
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            % push field values back to main app and close
            if app.AFlag == 1
                push_A(app.MainApp, app.A_Aim, app.sup)
                delete(app)
            end
        end

        % Value changed function: xSlider
        function xSliderValueChanged(app, event)
            app.xField.Value = app.xSlider.Value;
            % offset field in x direction
            mod_field(app);
            updateui(app);
        end

        % Value changed function: ySlider
        function ySliderValueChanged(app, event)
            app.yField.Value = app.ySlider.Value;
            % offset field in y direction
            mod_field(app);
            updateui(app);
        end

        % Value changed function: zSlider
        function zSliderValueChanged(app, event)
            app.zField.Value = app.zSlider.Value;
            % offset field in z direction
            mod_field(app);
            updateui(app);          
        end

        % Value changed function: xField
        function xFieldValueChanged(app, event)
            app.xSlider.Value = app.xField.Value;
            mod_field(app);
            updateui(app);     
            
        end

        % Value changed function: yField
        function yFieldValueChanged(app, event)
            app.ySlider.Value = app.yField.Value;
            mod_field(app);
            updateui(app);     
            
        end

        % Value changed function: zField
        function zFieldValueChanged(app, event)
            app.zSlider.Value = app.zField.Value;
            mod_field(app);
            updateui(app);     
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'x (mm)')
            ylabel(app.UIAxes, 'y (mm)')
            zlabel(app.UIAxes, 'z (mm)')
            app.UIAxes.XLim = [-200 200];
            app.UIAxes.YLim = [-200 200];
            app.UIAxes.ZLim = [-85 210];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [-200 0 200];
            app.UIAxes.XTickLabel = {'-200'; '0'; '200'};
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [-200 0 200];
            app.UIAxes.YTickLabel = {'-200'; '0'; '200'};
            app.UIAxes.ZTick = [-85 0 210];
            app.UIAxes.ZTickLabel = {'-85'; '0'; '210'};
            app.LoadNiftiButton = uibutton(app.UIFigure, 'push');
            app.LoadNiftiButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNiftiButtonPushed, true);
            app.LoadNiftiButton.Text = 'Load Nifti';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish!';
            app.xSliderLabel = uilabel(app.UIFigure);
            app.xSliderLabel.Text = 'x';
            app.xSlider = uislider(app.UIFigure);
            app.xSlider.Limits = [-40 40];
            app.xSlider.MajorTicks = [-40 -20 0 20 40];
            app.xSlider.MajorTickLabels = {'-40', '-20', '0', '20', '40'};
            app.xSlider.ValueChangedFcn = createCallbackFcn(app, @xSliderValueChanged, true);
            app.xSlider.MinorTicks = [-40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40];
            app.ySliderLabel = uilabel(app.UIFigure);
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.UIFigure);
            app.ySlider.Limits = [-40 40];
            app.ySlider.MajorTicks = [-40 -20 0 20 40];
            app.ySlider.MajorTickLabels = {'-40', '-20', '0', '20', '40'};
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @ySliderValueChanged, true);
            app.ySlider.MinorTicks = [-40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40];
            app.zSliderLabel = uilabel(app.UIFigure);
            app.zSliderLabel.Text = 'z';
            app.zSlider = uislider(app.UIFigure);
            app.zSlider.Limits = [-40 40];
            app.zSlider.MajorTicks = [-40 -20 0 20 40];
            app.zSlider.MajorTickLabels = {'-40', '-20', '0', '20', '40'};
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @zSliderValueChanged, true);
            app.zSlider.MinorTicks = [-40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40];
            app.zField = uieditfield(app.UIFigure, 'numeric');
            app.zField.ValueChangedFcn = createCallbackFcn(app, @zFieldValueChanged, true);
            app.yField = uieditfield(app.UIFigure, 'numeric');
            app.yField.ValueChangedFcn = createCallbackFcn(app, @yFieldValueChanged, true);
            app.xField = uieditfield(app.UIFigure, 'numeric');
            app.xField.ValueChangedFcn = createCallbackFcn(app, @xFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpeningVoonData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end