classdef process_coil < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InvertaxesCheckBox              matlab.ui.control.CheckBox
        zoffsetinmmEditField            matlab.ui.control.NumericEditField
        OffsetinmmLabel                 matlab.ui.control.Label
        UnitButtonGroup                 matlab.ui.container.ButtonGroup
        mmButton                        matlab.ui.control.RadioButton
        mButton                         matlab.ui.control.RadioButton
        inchButton                      matlab.ui.control.RadioButton
        WirelengthEditField             matlab.ui.control.EditField
        WirelengthEditFieldLabel        matlab.ui.control.Label
        InductanceEditField             matlab.ui.control.EditField
        InductanceEditFieldLabel        matlab.ui.control.Label
        WorstcoilwireresolutionEditField  matlab.ui.control.EditField
        WorstcoilwireresolutionEditFieldLabel  matlab.ui.control.Label
        AvgcoilwireresolutionEditField  matlab.ui.control.EditField
        AvgcoilwireresolutionEditFieldLabel  matlab.ui.control.Label
        CoordinateSystemSwitch          matlab.ui.control.Switch
        CoordinateSystemLabel           matlab.ui.control.Label
        FinishandBackButton             matlab.ui.control.Button
        CalculateFieldButton            matlab.ui.control.Button
        LoadCoilConductorPathButton     matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

%%
%   TMS KoDeIn Box, Version 1.0, 12/2022
%   Kaiserslautern Coil Design and Instrument Box
%   Max Koehler and Stefan M. Goetz
%   (c) 2006-2022


    properties (Access = public)
        coil
        coilraw
        dAdtcoil
        vol
        sup
        AFlag

        MainApp
        res
        roi
    end
    
    methods (Access = private)
%%  Function Update User Interface

        function updateui(app)

            for n=1:size(app.coilraw,3)

                plot3(app.UIAxes,app.coil(:,1,n),app.coil(:,2,n),app.coil(:,3,n),'k');
                
                if n==1
                    hold (app.UIAxes,'on')
                end

            end
            
            hold (app.UIAxes,'off')
            
            if app.AFlag ==1

                hold (app.UIAxes,'on')
                
                %// Get the current colormap
                currentColormap = colormap(hot);
                close(gcf)
                n=20;
                q=quiver3(app.UIAxes,app.sup(:,1:n:end),app.sup(:,2:n:end),app.sup(:,3:n:end),app.vol(:,1:n:end),app.vol(:,2:n:end),app.vol(:,3:n:end),2);
            
                %// Compute the magnitude of the vectors
                mags = sqrt(sum(cat(2, q.UData(:), q.VData(:), ...
                        reshape(q.WData, numel(q.UData), [])).^2, 2));
            
                %// Now determine the color to make each arrow using a colormap
                [~, ~, ind] = histcounts(mags, size(currentColormap, 1));
            
                %// Now map this to a colormap to get RGB
                cmap = uint8(ind2rgb(ind(:), currentColormap) * 255);
                cmap(:,:,4) = 255;
                cmap = permute(repmat(cmap, [1 3 1]), [2 1 3]);
            
                %// We repeat each color 3 times (using 1:3 below) because each arrow has 3 vertices
                set(q.Head, ...
                    'ColorBinding', 'interpolated', ...
                    'ColorData', reshape(cmap(1:3,:,:), [], 4).');   %'
            
                %// We repeat each color 2 times (using 1:2 below) because each tail has 2 vertices
                set(q.Tail, ...
                    'ColorBinding', 'interpolated', ...
                    'ColorData', reshape(cmap(1:2,:,:), [], 4).');

                hold (app.UIAxes,'off')
                     
            end
            

            for n=1:size(app.coilraw,3)

                cdiff = diff(app.coil(:,:,n));
                ncdiff(:,n) = sqrt(cdiff(:,1).^2+cdiff(:,2).^2+cdiff(:,3).^2);

            end

            maxdiff = max(max(ncdiff));
            mncdiff = mean(reshape(ncdiff,[],1));

            b1=6.5;
            b2=20;

            if mncdiff < b1
                app.AvgcoilwireresolutionEditField.BackgroundColor = "green";
            elseif (b1 <= mncdiff) && (mncdiff <= b2)
                app.AvgcoilwireresolutionEditField.BackgroundColor = "yellow";
            elseif b2 < mncdiff
                app.AvgcoilwireresolutionEditField.BackgroundColor = "red";
            end

            if maxdiff < b1
                app.WorstcoilwireresolutionEditField.BackgroundColor = "green";
            elseif (b1 <= maxdiff) && (maxdiff <= b2)
                app.WorstcoilwireresolutionEditField.BackgroundColor = "yellow";
            elseif b2 < maxdiff
                app.WorstcoilwireresolutionEditField.BackgroundColor = "red";
            end

            app.AvgcoilwireresolutionEditField.Value=[num2str(mncdiff,'%.2f'),' mm'];
            app.WorstcoilwireresolutionEditField.Value=[num2str(maxdiff,'%.2f'),' mm'];

        end
        
%% Function Calculate Inductance and Wire Length        
        function induct_wireleng(app)
    

            wireradius = 2.5e-3;                        % Radius of the wire (on axis towards origin)
            cdistribfactor = 1/3;                       % Current distribution (0: perfect skin effect, 1/4: equal/homogeneous distrib)
            mu0 = 4*pi*1E-7;                            % magnetic permeability of free space
            
            for n=1:size(app.coilraw,3) %loop through each coil segment

                coilm=app.coil(:,:,n).*1e-3; %convert to m
    
                Pdiff = diff(coilm); %diff. between consecutive points
                mylength(n) = sum(sqrt(sum(Pdiff.^2, 2))); %total length of coil segment by sum of Euclidean distances between each pair of points
    
                %additional points to make coil appear continuous
                section=mylength(n)/size(coilm,1);
                addpoints=round(norm(coilm(1,:)-coilm(end,:))/section);
    
                pts(:,1)=linspace(coilm(end,1),coilm(1,1),addpoints+1);
                pts(:,2)=linspace(coilm(end,2),coilm(1,2),addpoints+1);
                pts(:,3)=linspace(coilm(end,3),coilm(1,3),addpoints+1);
    
                pts=pts(2:end-1,:);
    
                coilm=[coilm;pts];
                %interpolate halfway points - allows higher resolution for
                %inductance calculations
                coilseg(:,1) = interp1(coilm(:,1), 1:1/2:size(coilm,1), 'pchip');
                coilseg(:,2) = interp1(coilm(:,2), 1:1/2:size(coilm,1), 'pchip');
                coilseg(:,3) = interp1(coilm(:,3), 1:1/2:size(coilm,1), 'pchip');
                
                % init: mutual inductance calculation
                Lfact = 0;
                
                for icnt=2:size(coilseg,1)
                        
                    D = sqrt((coilseg(icnt,1)-coilseg(2:end,1)).^2 + (coilseg(icnt,2)-coilseg(2:end,2)).^2 + (coilseg(icnt,3)-coilseg(2:end,3)).^2);    % starting with 2:end, because need for Lfact the same size (N points lead to N-1 segments!)
                    Pdiff = diff(coilseg);
                    Lfact = Lfact + sum((((coilseg(icnt,1)-coilseg(icnt-1,1))*Pdiff(:,1)) + ((coilseg(icnt,2)-coilseg(icnt-1,2))*Pdiff(:,2)) + ((coilseg(icnt,3)-coilseg(icnt-1,3))*Pdiff(:,3)))./max(D, wireradius));
                    
                end
                
                L(n) = mu0/(4*pi) * (Lfact + 2*mylength(n)*cdistribfactor);
                
                clear section addpoints pts Lfact coilm coilseg

            end

            %update length and av inductance
            av_L=mean(L);
            av_mylength=mean(mylength);

            if av_L > 0
                app.InductanceEditField.BackgroundColor = "green";
            else
                app.InductanceEditField.BackgroundColor = "red";
            end

            if av_mylength ~= 0
                app.WirelengthEditField.BackgroundColor = "green";
            else
                app.WirelengthEditField.BackgroundColor = "red";
            end

            app.InductanceEditField.Value=[num2str(av_L*1e6,'%.2f'),' µH'];
            app.WirelengthEditField.Value=[num2str(av_mylength,'%.2f'),' m'];
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, m_roi, m_res)
            app.res = m_res;
            app.roi = m_roi;

            app.MainApp = mainapp;
        end

        % Button pushed function: LoadCoilConductorPathButton
        function LoadCoilConductorPathButtonPushed(app, event)
            
            app.AFlag=0;
%uigetfile - opens dialog to select file
%filepath & name stored, opens file into app.coilraw
            [myfilename, mypath]=uigetfile({'*.mat; *.xlsx; *.xls; *.csv','Supported coil wire path files (*.mat, *.xlsx, *.xls, *.csv)'; '*.mat','Matlab data files (*.mat)'; '*.xlsx; *.xls','Excel file (*.xlsx, *.xls)'; '*.csv','Comma-separated values (*.csv)'}, 'Select coil conductor path file ...');
            
            if (~isequal(myfilename, 0)) && (~isequal(mypath, 0))    % both 0 means user clicked on cancel
            
                % check format
                if strfind(myfilename(length(myfilename)-3:length(myfilename)), '.mat')
                    % Matlab .mat file
                
                    app.coilraw=importdata([mypath myfilename]);          
                
                elseif strfind(myfilename(length(myfilename)-4:length(myfilename)), '.xlsx')
                    % xml Excel file
                    % read from line two on
                    
                    tmp_xlsfilesheet1 = xlsread([mypath myfilename]);
                    app.coilraw = tmp_xlsfilesheet1;
                    
                    clear tmp_xlsfilesheet1
                    
                elseif strfind(myfilename(length(myfilename)-3:length(myfilename)), '.xls')
                    % old Excel file
                    % read from line two on
                    
                    tmp_xlsfilesheet1 = xlsread([mypath myfilename]);
                    app.coilraw = tmp_xlsfilesheet1; 
                    
                    clear tmp_xlsfilesheet1
                    
                elseif strfind(myfilename(length(myfilename)-3:length(myfilename)), '.csv')
                    % CSV file
                    % read from lie two on
                    
                    tmp_csvfile = importdata([mypath myfilename]);
                    app.coilraw = tmp_csvfile.data;
                    
                    clear tmp_csvfile
                    
                else
                    % unknown file type
                    msgbox('Unknown file type.', 'Coil file', 'error')
                    return
                end
                
                clear oldFolder myfilename mypath

                %reshapes app.coilraw data, grouping every three columns
                %   into seperate layers
                % into 3D matrix coilrawtmp, which is assigned back to
                %   app.coilraw and app.coil
                for  n=1:fix(size(app.coilraw,2)/3)

                    coilrawtmp(:,:,n)=app.coilraw(:,1+((n-1)*3):3+((n-1)*3));

                end

                app.coilraw=coilrawtmp;

                app.coil=app.coilraw;
                
                UnitButtonGroupSelectionChanged(app)
                induct_wireleng(app);
                updateui(app);
                
            else
                % in case they clicked on cancel
                
            end
                
        end

        % Selection changed function: UnitButtonGroup
        function UnitButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.UnitButtonGroup.SelectedObject;
            value = app.zoffsetinmmEditField.Value; %offset value z
            
            if app.AFlag == 0

                %converts raw coil data into correct units
                if app.mmButton.Value == true
                    app.coil=app.coilraw;
                elseif app.mButton.Value == true
                    app.coil=app.coilraw.*1000;
                elseif app.inchButton.Value == true
                    app.coil=app.coilraw.*25.4;
                end
    

                if app.mmButton.Value == true
                    app.coil(:,3,:)=app.coilraw(:,3,:)+value;
                elseif app.mButton.Value == true
                    app.coil(:,3,:)=(app.coilraw(:,3,:)+(value/1000)).*1000;
                elseif app.inchButton.Value == true
                    app.coil(:,3,:)=(app.coilraw(:,3,:)+(value/25.4)).*25.4;
                end
    
                induct_wireleng(app);
                updateui(app);
    
                if app.inchButton.Value == true
                    [y,Fs] = audioread('eagle.mp3');
                    player=audioplayer(y,Fs);
                    playblocking(player)
                end

            end

        end

        % Value changed function: zoffsetinmmEditField
        function zoffsetinmmEditFieldValueChanged(app, event)
            
            value = app.zoffsetinmmEditField.Value;
            
            if app.mmButton.Value == true
                app.coil(:,3,:)=app.coilraw(:,3,:)+value;
            elseif app.mButton.Value == true
                app.coil(:,3,:)=(app.coilraw(:,3,:)+(value/1000)).*1000;
            elseif app.inchButton.Value == true
                app.coil(:,3,:)=(app.coilraw(:,3,:)+(value/25.4)).*25.4;
            end

            induct_wireleng(app);
            updateui(app);

        end

        % Value changed function: CoordinateSystemSwitch
        function CoordinateSystemSwitchValueChanged(app, event)
            
            if app.AFlag == 0

                app.coil(:,[1 2 3],:)=app.coil(:,[2 1 3],:);
                updateui(app);

            end

        end

        % Button pushed function: CalculateFieldButton
        function CalculateFieldButtonPushed(app, event)
            %app.roi - radius of ROI
            %app.res - resolution of grid (smallest distance between grid points)

            gridpoints = ((app.roi*2)/app.res)+1; %no. of grid points in one direction (diameter / resolution)


            [xx,yy,zz]= meshgrid(linspace(-app.roi,app.roi,gridpoints),linspace(-app.roi,app.roi,gridpoints),linspace(-app.roi,app.roi,gridpoints));
            %3D grid of coordinates, spanning -roi to +roi -- calculated
            %   from simple linspace (diameter divided into spaces of res)

            %xx, yy, zz reshaped into columns of app.sup
            app.sup(:,1)=reshape(xx,[],1);
            app.sup(:,2)=reshape(yy,[],1);
            app.sup(:,3)=reshape(zz,[],1);

            no = vecnorm(app.sup,2,2);
            app.sup(no>app.roi,:) =  [];
            
            %initialized app.vol to store calculated A-field;
            % no. of rows same as app.sup, 3 col.
            app.vol=zeros(size(app.sup,1),3);

            Mu0=            1E-7;           % Magnetic field constant/4*pi
            preconst=       Mu0;

            sups=size(app.sup,1);
            supi=app.sup;

            %progress dialog displayed while calculations occuring
            d = uiprogressdlg(app.UIFigure,'Icon','membrane.png', 'Title','Please Wait','Message','Calculating A-Field');
            d.Value = 0;


            %app.coilraw stores multiple strands of coil, with each slice
            %   along 3rd dimension representing one strand
            for n=1:size(app.coilraw,3)
                
                coil2=app.coil(:,:,n); %stores current strand's coordinates
                coilvec=diff(coil2); %vector diff between adj. points in coil (dL in Biot-Savart law)
                coil2(end,:)=[];


                %calc. of A
                parfor i=1:sups %parallel computation
                    
                    normdist = sqrt((coil2(:,1)-supi(i,1)).*(coil2(:,1)-supi(i,1))...
                                   +(coil2(:,2)-supi(i,2)).*(coil2(:,2)-supi(i,2))...
                                   +(coil2(:,3)-supi(i,3)).*(coil2(:,3)-supi(i,3)));
            
                    dAdtcoil(i,:) = preconst*sum((1./normdist).*coilvec(:,:),1);
            
                end
                
                app.vol=app.vol+dAdtcoil/size(app.coilraw,3); %divide by the number of strands

                clear dAdtcoil

                d.Value = d.Value + 1/size(app.coilraw,3);

            end

            disp(['The value is: %\dn', num2str(max(app.vol))])

            app.AFlag=1; %indicates calculations stopped
            close(d)
            updateui(app);

        end

        % Button pushed function: FinishandBackButton
        function FinishandBackButtonPushed(app, event)
            % push field back to main app and close (check if field
            % calculation was successfull. If not, close with warning.
            if app.AFlag == 1
                push_A(app.MainApp, app.vol, app.sup)
                delete(app)
            end

        end

        % Value changed function: InvertaxesCheckBox
        function InvertaxesCheckBoxValueChanged(app, event)
            value = app.InvertaxesCheckBox.Value;

            if app.AFlag == 0

                app.coil(:,1,:)= -app.coil(:,1,:);
                app.coil(:,2,:)= -app.coil(:,2,:);
                updateui(app);

            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'x (mm)')
            ylabel(app.UIAxes, 'y (mm)')
            zlabel(app.UIAxes, 'z (mm)')
            app.UIAxes.XLim = [-200 200];
            app.UIAxes.YLim = [-200 200];
            app.UIAxes.ZLim = [-85 210];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [-200 0 200];
            app.UIAxes.XTickLabel = {'-200'; '0'; '200'};
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [-200 0 200];
            app.UIAxes.YTickLabel = {'-200'; '0'; '200'};
            app.UIAxes.ZTick = [-85 0 210];
            app.UIAxes.ZTickLabel = {'-85'; '0'; '210'};
            app.LoadCoilConductorPathButton = uibutton(app.UIFigure, 'push');
            app.LoadCoilConductorPathButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCoilConductorPathButtonPushed, true);
            app.LoadCoilConductorPathButton.Tooltip = {'Load file (mat, excel, comma-separated-value file) of a coil path or several parallel paths in millimeters, meters, or inchs (set unit below after loading the file). If the coil is not correctly displayed in the graph on the right after loading the file, adjust the units and/or the z offset.'};
            app.LoadCoilConductorPathButton.Text = 'Load Coil Conductor Path';
            app.CalculateFieldButton = uibutton(app.UIFigure, 'push');
            app.CalculateFieldButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFieldButtonPushed, true);
            app.CalculateFieldButton.Tooltip = {'Calculate the magnetic vector potential A for the coil path. Calculations might take a while. The results will be displayed on the right.'};
            app.CalculateFieldButton.Text = 'Calculate Field';
            app.FinishandBackButton = uibutton(app.UIFigure, 'push');
            app.FinishandBackButton.ButtonPushedFcn = createCallbackFcn(app, @FinishandBackButtonPushed, true);
            app.FinishandBackButton.Tooltip = {''};
            app.FinishandBackButton.Text = 'Finish and Back!';
            app.CoordinateSystemLabel = uilabel(app.UIFigure);
            app.CoordinateSystemLabel.Text = 'Coordinate System';
            app.CoordinateSystemSwitch = uiswitch(app.UIFigure, 'slider');
            app.CoordinateSystemSwitch.Items = {'left', 'right'};
            app.CoordinateSystemSwitch.ValueChangedFcn = createCallbackFcn(app, @CoordinateSystemSwitchValueChanged, true);
            app.CoordinateSystemSwitch.Tooltip = {'Switch between right-hand and left-hand (often used in Matlab) coordinate systems, essentially swapping x and y.'};
            app.CoordinateSystemSwitch.Value = 'left';
            app.AvgcoilwireresolutionEditFieldLabel = uilabel(app.UIFigure);
            app.AvgcoilwireresolutionEditFieldLabel.Text = 'Avg. coil wire resolution';
            app.AvgcoilwireresolutionEditField = uieditfield(app.UIFigure, 'text');
            app.AvgcoilwireresolutionEditField.Tooltip = {'Spacing of sample points of the coil path representing the resolution of the coil description.'};
            app.WorstcoilwireresolutionEditFieldLabel = uilabel(app.UIFigure);
            app.WorstcoilwireresolutionEditFieldLabel.Text = 'Worst coil wire resolution';
            app.WorstcoilwireresolutionEditField = uieditfield(app.UIFigure, 'text');
            app.InductanceEditFieldLabel = uilabel(app.UIFigure);
            app.InductanceEditFieldLabel.Text = 'Inductance';
            app.InductanceEditField = uieditfield(app.UIFigure, 'text');
            app.InductanceEditField.Tooltip = {'Magnetic inductance of the coil (without any cable or connectors attached). Most TMS devices expect around 10 µH.'};
            app.WirelengthEditFieldLabel = uilabel(app.UIFigure);
            app.WirelengthEditFieldLabel.Text = 'Wire length';
            app.WirelengthEditField = uieditfield(app.UIFigure, 'text');
            app.WirelengthEditField.Tooltip = {'Required length of the conductor for the coil only if the coil is to be implemented (ignoring the coil cable).'};
            app.UnitButtonGroup = uibuttongroup(app.UIFigure);
            app.UnitButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UnitButtonGroupSelectionChanged, true);
            app.UnitButtonGroup.Tooltip = {'You may have to switch between the units to adjust them to your coil path file. It often is the solution if you do not see a coil in the graph on the side after loading a file.'};
            app.UnitButtonGroup.Title = 'Unit';
            app.inchButton = uiradiobutton(app.UnitButtonGroup);
            app.inchButton.Text = 'inch';
            app.mButton = uiradiobutton(app.UnitButtonGroup);
            app.mButton.Text = 'm';
            app.mmButton = uiradiobutton(app.UnitButtonGroup);
            app.mmButton.Text = 'mm';
            app.mmButton.Value = true;
            app.OffsetinmmLabel = uilabel(app.UIFigure);
            app.OffsetinmmLabel.Text = 'z offset (in mm)';
            app.zoffsetinmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.zoffsetinmmEditField.Limits = [-200 200];
            app.zoffsetinmmEditField.ValueChangedFcn = createCallbackFcn(app, @zoffsetinmmEditFieldValueChanged, true);
            app.zoffsetinmmEditField.Tooltip = {'Adjust the zero offset in z direction, e.g., to 115 mm for the zero position of SimNIBS or to add the thickness of the bottom shell of a coiil and account for the thickness of the coil conductor.'};
            app.InvertaxesCheckBox = uicheckbox(app.UIFigure);
            app.InvertaxesCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertaxesCheckBoxValueChanged, true);
            app.InvertaxesCheckBox.Text = 'Invert axes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = process_coil(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end